classdef IgnWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Name_to_save                    matlab.ui.control.EditField
        ProvideanametotheigniterLabel   matlab.ui.control.Label
        SAVEButton                      matlab.ui.control.Button
        UIHeat_cap_vct                  matlab.ui.control.NumericEditField
        SpecificheatatconstantvolumeJmolKEditFieldLabel  matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
        UIGamma                         matlab.ui.control.NumericEditField
        RatioofspecificheatsEditFieldLabel  matlab.ui.control.Label
        UITign                          matlab.ui.control.NumericEditField
        IgnitertemperatureKLabel        matlab.ui.control.Label
        N_ign_datapoints                matlab.ui.control.NumericEditField
        NumberofigniterdatapointsLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        CallingApp 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, ign_read)
            % Saves main app and inputs
            app.CallingApp = mainapp;
            
            app.UITign.Value = ign_read(1,2);
            app.UIGamma.Value = ign_read(1,3);
            app.UIHeat_cap_vct.Value = ign_read(1,4);
            app.N_ign_datapoints.Value = ign_read(1,1);
            
            app.UITable.Data = ign_read(2:3,1:ign_read(1,1)).';
        end

        % Button pushed function: SAVEButton
        function SAVEButtonPushed(app, event)
            if ~isempty(app.Name_to_save.Value)
                full_ign_name = cat(2,'C:\Program Files\PropSIM\application\Libraries\Ign\',app.Name_to_save.Value,'.csv');    
                ign_save = zeros(3,app.N_ign_datapoints.Value);
                ign_save(1,1:4) = [app.N_ign_datapoints.Value, app.UITign.Value, app.UIGamma.Value, app.UIHeat_cap_vct.Value];
                ign_save(2:3,1:ign_save(1,1)) = app.UITable.Data.';
                
                
                writematrix(ign_save,full_ign_name);
                UI_editign_update(app.CallingApp,ign_save,cat(2,app.Name_to_save.Value,'.csv'))
                
                delete(app);
            else
                
            end 
        end

        % Value changed function: N_ign_datapoints
        function N_ign_datapointsValueChanged(app, event)
            value = app.N_ign_datapoints.Value;
            app.UITable.Data = zeros(value,2);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.NumberofigniterdatapointsLabel = uilabel(app.UIFigure);
            app.NumberofigniterdatapointsLabel.Text = 'Number of igniter data points';
            app.N_ign_datapoints = uieditfield(app.UIFigure, 'numeric');
            app.N_ign_datapoints.ValueChangedFcn = createCallbackFcn(app, @N_ign_datapointsValueChanged, true);
            app.IgnitertemperatureKLabel = uilabel(app.UIFigure);
            app.IgnitertemperatureKLabel.Text = 'Igniter temperature (K)';
            app.UITign = uieditfield(app.UIFigure, 'numeric');
            app.RatioofspecificheatsEditFieldLabel = uilabel(app.UIFigure);
            app.RatioofspecificheatsEditFieldLabel.Text = 'Ratio of specific heats';
            app.UIGamma = uieditfield(app.UIFigure, 'numeric');
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Mass flow (kg/s)'; 'Time (s)'};
            app.UITable.RowName = {};
            app.SpecificheatatconstantvolumeJmolKEditFieldLabel = uilabel(app.UIFigure);
            app.SpecificheatatconstantvolumeJmolKEditFieldLabel.Text = {'Specific heat at constant'; 'volume (J/molK)'};
            app.UIHeat_cap_vct = uieditfield(app.UIFigure, 'numeric');
            app.SAVEButton = uibutton(app.UIFigure, 'push');
            app.SAVEButton.ButtonPushedFcn = createCallbackFcn(app, @SAVEButtonPushed, true);
            app.SAVEButton.Icon = 'save_icon.png';
            app.SAVEButton.Text = 'SAVE';
            app.ProvideanametotheigniterLabel = uilabel(app.UIFigure);
            app.ProvideanametotheigniterLabel.Text = 'Provide a name to the igniter';
            app.Name_to_save = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = IgnWindow(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end