classdef MatWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SAVEButton                      matlab.ui.control.Button
        Name_to_save                    matlab.ui.control.EditField
        ProvideanametothematerialLabel  matlab.ui.control.Label
        N_uts_datapoints                matlab.ui.control.NumericEditField
        NumberofUTSdatapointsEditFieldLabel  matlab.ui.control.Label
        N_yield_datapoints              matlab.ui.control.NumericEditField
        NumberofyielddatapointsEditFieldLabel  matlab.ui.control.Label
        UITable_uts                     matlab.ui.control.Table
        UIMeltTemp                      matlab.ui.control.NumericEditField
        MeltingtemperatureCLabel        matlab.ui.control.Label
        UIPoisson                       matlab.ui.control.NumericEditField
        PoissonratioEditFieldLabel      matlab.ui.control.Label
        UIDensity                       matlab.ui.control.NumericEditField
        Densitykgm3EditFieldLabel       matlab.ui.control.Label
        UILambda                        matlab.ui.control.NumericEditField
        ThermalconductivityWmKEditFieldLabel  matlab.ui.control.Label
        UITable_yield                   matlab.ui.control.Table
        UIYoungModulus                  matlab.ui.control.NumericEditField
        YoungModulusGPaLabel            matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp
        mode
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, P, Y, U, M)
            % Saves main app and inputs
            app.CallingApp = mainapp;
            % Inputs P, Y, U         
            
            % Update interface
            app.UIYoungModulus.Value = P(1);
            app.UIDensity.Value = P(2);
            app.UIPoisson.Value = P(3);
            app.UIMeltTemp.Value = P(4);
            app.UILambda.Value = P(5);
            app.N_yield_datapoints.Value = length(Y(1,:));
            app.N_uts_datapoints.Value = length(U(1,:));
            app.UITable_yield.Data = Y.'; 
            app.UITable_uts.Data = U.';
            
            app.mode = M; % 1 - For chamber, 0 - For nozzle
        end

        % Value changed function: N_yield_datapoints
        function N_yield_datapointsValueChanged(app, event)
            value = app.N_yield_datapoints.Value;
            app.UITable_yield.Data = zeros(value,2);
        end

        % Value changed function: N_uts_datapoints
        function N_uts_datapointsValueChanged(app, event)
            value = app.N_uts_datapoints.Value;
            app.UITable_uts.Data = zeros(value,2);   
        end

        % Button pushed function: SAVEButton
        function SAVEButtonPushed(app, event)
            if ~isempty(app.Name_to_save.Value)
                full_mat_name = cat(2,'C:\Program Files\PropSIM\application\Libraries\Mat\',app.Name_to_save.Value,'.csv');    
                mat_save = zeros(8,max([5, app.N_yield_datapoints.Value, app.N_uts_datapoints.Value]));
                mat_save(1,1) = 5;  % This will fail if the material properties are lengthened                        
                mat_save(2,1:mat_save(1,1)) = [app.UIYoungModulus.Value, app.UIDensity.Value, app.UIPoisson.Value, app.UIMeltTemp.Value, app.UILambda.Value];
                mat_save(3,1) = app.N_yield_datapoints.Value;
                mat_save(4:5,1:mat_save(3,1)) = app.UITable_yield.Data.';
                mat_save(6,1) = app.N_uts_datapoints.Value;
                mat_save(7:8,1:mat_save(6,1)) = app.UITable_uts.Data.';
                writematrix(mat_save,full_mat_name);
                
                if app.mode
                    UI_editmat_update(app.CallingApp,mat_save,cat(2,app.Name_to_save.Value,'.csv'),1);
                else
                    UI_editmat_update(app.CallingApp,mat_save,cat(2,app.Name_to_save.Value,'.csv'),0);
                end
                
                delete(app);
            else
                
            end 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.YoungModulusGPaLabel = uilabel(app.UIFigure);
            app.YoungModulusGPaLabel.Text = {'Young Modulus'; ' (GPa)'};
            app.UIYoungModulus = uieditfield(app.UIFigure, 'numeric');
            app.UITable_yield = uitable(app.UIFigure);
            app.UITable_yield.ColumnName = {'T (ºC)'; 'Yield (MPa)'};
            app.UITable_yield.RowName = {''; ''};
            app.UITable_yield.ColumnEditable = true;
            app.ThermalconductivityWmKEditFieldLabel = uilabel(app.UIFigure);
            app.ThermalconductivityWmKEditFieldLabel.Text = {'Thermal conductivity'; '(W/mK)'};
            app.UILambda = uieditfield(app.UIFigure, 'numeric');
            app.Densitykgm3EditFieldLabel = uilabel(app.UIFigure);
            app.Densitykgm3EditFieldLabel.Text = 'Density (kg/m3)';
            app.UIDensity = uieditfield(app.UIFigure, 'numeric');
            app.PoissonratioEditFieldLabel = uilabel(app.UIFigure);
            app.PoissonratioEditFieldLabel.Text = 'Poisson ratio';
            app.UIPoisson = uieditfield(app.UIFigure, 'numeric');
            app.MeltingtemperatureCLabel = uilabel(app.UIFigure);
            app.MeltingtemperatureCLabel.Text = {'Melting '; 'temperature (ºC)'};
            app.UIMeltTemp = uieditfield(app.UIFigure, 'numeric');
            app.UITable_uts = uitable(app.UIFigure);
            app.UITable_uts.ColumnName = {'T (ºC)'; 'UTS (MPa)'};
            app.UITable_uts.RowName = {''; ''};
            app.UITable_uts.ColumnEditable = true;
            app.NumberofyielddatapointsEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofyielddatapointsEditFieldLabel.Text = 'Number of yield data points';
            app.N_yield_datapoints = uieditfield(app.UIFigure, 'numeric');
            app.N_yield_datapoints.ValueChangedFcn = createCallbackFcn(app, @N_yield_datapointsValueChanged, true);
            app.NumberofUTSdatapointsEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofUTSdatapointsEditFieldLabel.Text = 'Number of UTS data points';
            app.N_uts_datapoints = uieditfield(app.UIFigure, 'numeric');
            app.N_uts_datapoints.ValueChangedFcn = createCallbackFcn(app, @N_uts_datapointsValueChanged, true);
            app.ProvideanametothematerialLabel = uilabel(app.UIFigure);
            app.ProvideanametothematerialLabel.Text = 'Provide a name to the material';
            app.Name_to_save = uieditfield(app.UIFigure, 'text');
            app.SAVEButton = uibutton(app.UIFigure, 'push');
            app.SAVEButton.ButtonPushedFcn = createCallbackFcn(app, @SAVEButtonPushed, true);
            app.SAVEButton.Icon = 'save_icon.png';
            app.SAVEButton.Text = 'SAVE';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MatWindow(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end