classdef MySim < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        CANCELButton                matlab.ui.control.Button
        PlotPanel                   matlab.ui.container.Panel
        EDITIgn                     matlab.ui.control.Button
        SIMLOG                      matlab.ui.control.TextArea
        SIMULATIONLOGTextAreaLabel  matlab.ui.control.Label
        EDITMat_no                  matlab.ui.control.Button
        EDITProp                    matlab.ui.control.Button
        EDITMat_ch                  matlab.ui.control.Button
        OPENIgn                     matlab.ui.control.Button
        OPENMat_no                  matlab.ui.control.Button
        OPENProp                    matlab.ui.control.Button
        OPENCase                    matlab.ui.control.Button
        OPENMat_ch                  matlab.ui.control.Button
        SAVEButton                  matlab.ui.control.Button
        List_curves                 matlab.ui.control.TextArea
        LOADCURVESButton            matlab.ui.control.Button
        Noz_union                   matlab.ui.control.NumericEditField
        NozunionNEditFieldLabel     matlab.ui.control.Label
        Name_to_save                matlab.ui.control.EditField
        ProvideanametothecaseEditFieldLabel  matlab.ui.control.Label
        Crack_type                  matlab.ui.control.DropDown
        PropellantcrackingtypeDropDownLabel  matlab.ui.control.Label
        t_sim                       matlab.ui.control.NumericEditField
        SimtsEditFieldLabel         matlab.ui.control.Label
        n_sim                       matlab.ui.control.NumericEditField
        nEditFieldLabel             matlab.ui.control.Label
        N_sim                       matlab.ui.control.NumericEditField
        FVMEditFieldLabel           matlab.ui.control.Label
        dt_sim                      matlab.ui.control.NumericEditField
        tsEditFieldLabel            matlab.ui.control.Label
        WmKEditField_2Label         matlab.ui.control.Label
        lambda_ins                  matlab.ui.control.NumericEditField
        c_ins                       matlab.ui.control.NumericEditField
        cJkgKEditField_2Label       matlab.ui.control.Label
        t_ins                       matlab.ui.control.NumericEditField
        tmmEditField_2Label         matlab.ui.control.Label
        lambda_li                   matlab.ui.control.NumericEditField
        WmKEditFieldLabel           matlab.ui.control.Label
        c_li                        matlab.ui.control.NumericEditField
        cJkgKEditFieldLabel         matlab.ui.control.Label
        t_li                        matlab.ui.control.NumericEditField
        tmmEditFieldLabel           matlab.ui.control.Label
        div_no                      matlab.ui.control.NumericEditField
        divEditFieldLabel           matlab.ui.control.Label
        conv_no                     matlab.ui.control.NumericEditField
        convEditFieldLabel          matlab.ui.control.Label
        De_no                       matlab.ui.control.NumericEditField
        DemmEditFieldLabel          matlab.ui.control.Label
        Dt_no                       matlab.ui.control.NumericEditField
        DtmmEditFieldLabel          matlab.ui.control.Label
        Di_no                       matlab.ui.control.NumericEditField
        DimmEditFieldLabel          matlab.ui.control.Label
        N_p                         matlab.ui.control.NumericEditField
        NgrainsLabel                matlab.ui.control.Label
        L0_p                        matlab.ui.control.NumericEditField
        L0mmEditFieldLabel          matlab.ui.control.Label
        Dint_p                      matlab.ui.control.NumericEditField
        DintmmEditField_2Label      matlab.ui.control.Label
        Dext_p                      matlab.ui.control.NumericEditField
        DextmmEditField_2Label      matlab.ui.control.Label
        Blk_union                   matlab.ui.control.NumericEditField
        BlkunionNEditFieldLabel     matlab.ui.control.Label
        L_ch                        matlab.ui.control.NumericEditField
        LchmmEditFieldLabel         matlab.ui.control.Label
        Dint_ch                     matlab.ui.control.NumericEditField
        DintmmEditFieldLabel        matlab.ui.control.Label
        Dext_ch                     matlab.ui.control.NumericEditField
        DextmmEditFieldLabel        matlab.ui.control.Label
        Pamb                        matlab.ui.control.NumericEditField
        PbarEditFieldLabel          matlab.ui.control.Label
        Tamb                        matlab.ui.control.NumericEditField
        TKEditFieldLabel            matlab.ui.control.Label
        CLEARButton                 matlab.ui.control.Button
        PLOTButton                  matlab.ui.control.Button
        SIMULATEButton              matlab.ui.control.Button
        Loaded_name_case            matlab.ui.control.Label
        Case_preset                 matlab.ui.control.Label
        Ign_label                   matlab.ui.control.Label
        Prop_label                  matlab.ui.control.Label
        Mat_no_label                matlab.ui.control.Label
        Mat_ch_label                matlab.ui.control.Label
        EndsinhibitedLabel          matlab.ui.control.Label
        OutsideinhibitedLabel       matlab.ui.control.Label
        Inh_end                     matlab.ui.control.CheckBox
        Inh_out                     matlab.ui.control.CheckBox
        IGNITERLabel                matlab.ui.control.Label
        CRACKINGLabel               matlab.ui.control.Label
        SIMULATORLabel              matlab.ui.control.Label
        INSULATORLabel              matlab.ui.control.Label
        LINERLabel                  matlab.ui.control.Label
        MATERIALLabel_2             matlab.ui.control.Label
        NOZZLELabel                 matlab.ui.control.Label
        PROPELLANTLabel             matlab.ui.control.Label
        GRAINLabel                  matlab.ui.control.Label
        MATERIALLabel               matlab.ui.control.Label
        CHAMBERLabel                matlab.ui.control.Label
        AMBIENTLabel                matlab.ui.control.Label
    end

    %% HERE ALL THE GLOBAL VARIABLES AND FUNCTIONS ARE LOCATED
    
    properties (Access = private) % I guess they're global variable so they can be rewritten
        loaded_curves = zeros(6,500,10); % 3D matrix with the data from all loaded result curves (should be 500)
        loaded_curves_index = 1; % The amount of curves loaded inside of loaded_curves
        loaded_curves_list = strings(10,1); % The filename of all the loaded curves, for the legend
        prop_ch; % Properties of the chamber material
        yield_ch; % Yield data points of the chamber material
        uts_ch; % UTS data points of the chamber material
        prop_no; % Properties of the nozzle material
        yield_no; % Yield data points of the nozzle material
        uts_no; % UTS data points of the nozzle material
        prop_prop; % Properties of the propellant
        IGN; % Igniter properties, time and mass flow data points
        SIM; % Simulation properties
        CH; % Chamber properties
        NOZ; % Nozzle properties
        PROP; % Propellant and grain properties
        results; % Results from execution are saved as global variables
        
        f_sim = false;
        f_cancel = false;
        
        sigma_rad = 5.6703*10^(-8); % Stefan-Boltzmann constant
        
        DialogApp
    end
    
    methods (Access = private)
        
        function UI_Resize(app,force)
            drawnow;
            if force == 1 % Also fixate the window
                figure(app.UIFigure)
                app.UIFigure.WindowState = 'maximized';
            end
            scroll(app.SIMLOG, 'bottom')
        end
        
        % Stress interpolation, works for both yield and UTS (you'd call it twice)
        function [st_intpl] = MAIN_stress_interpolation(app,T,yield_T,yield)
            if length(yield) == 1 % Only one tabulated value
                st_intpl = yield; 
            else
                yield_test = yield_T > T; % Compared requested T with the yield data point temperature
                int_yield = find(yield_test,1); % Saves the first data point over requested temperature
                if yield_test == ones(1,length(yield_test)) % All data points are over the requested T
                    st_intpl = (yield(2)-yield(1))/(yield_T(2)-yield_T(1))*(T-yield_T(1))+yield(1);
                elseif yield_test == zeros(1,length(yield_test)) % All data points are below the requested T
                    st_intpl = (yield(length(yield_test))-yield(length(yield_test)-1))/(yield_T(length(yield_test))...
                        -yield_T(length(yield_test)-1))*(T-yield_T(length(yield_test)-1))+yield(length(yield_test)-1);
                else
                    st_intpl = (yield(int_yield)-yield(int_yield-1))/(yield_T(int_yield)-yield_T(int_yield-1))*(T-yield_T(int_yield-1))+yield(int_yield-1);
                end 
            end
        end
        
        % Reduction of flame temperature due to combustion efficiency
        function T_flame = MAIN_flame_temp(app,T_flame_p,eff_comb)
            T_flame = T_flame_p*eff_comb;
            % IMPROVEMENT: effiency dependant on the pressure or some other model
        end
        
        % Mass flows out of the nozzle for choked and unchoked conditions
        function mdot = MAIN_choked_nozzle(app,Dt_no,P_ch,T_ch,Pamb,k_g,r_g,f_choked)
            At = pi/4*Dt_no^2;
            if f_choked == 1
                mdot = At*P_ch*sqrt(k_g/(r_g*T_ch)*(2/(k_g+1))^((k_g+1)/(k_g-1))); % we consider choked nozzle
            else
                mdot = 0.85*At*sqrt(2*P_ch/(r_g*T_ch)*(P_ch-Pamb)); % we consider quadrant edge orifice
                % IMPROVEMENT: using something other than quadrant edge orifice
            end
            
            if ~isreal(mdot) || mdot<0
                mdot = 0;
            end
        end
        
        % Erosive burning rate calculation
        function rdot_ero = MAIN_erosive_burning(app,dens_g,v_g,Dh,a_rdot,b_rdot,rdot_nom,dens_p)
            a_rdot = a_rdot*(rdot_nom*Dh)^(0.2)/dens_p; % Correction given by Hein
            % IMPROVEMENT: not using the correction
            G = dens_g*v_g;
            z = (b_rdot*dens_p*rdot_nom)/G;
            rdot_ero = a_rdot*G^(0.8)/(Dh^0.2*exp(z));
        end
        
        % 4th Order Runge-Kutta for Twall differential equation
        function twall_next = MAIN_Twall_4th_order_RK (app,diff_p,h_c,h_r,lambda_p,Tamb,Twall0,Tg1,Tg0,dt_sim)
            % x --> t // y --> Twall
            l = lambda_p; d = diff_p; h_t = h_r + h_c; % Short forms for clarity
            h = dt_sim/100; % Step-size a hundreth. A tenth already yielded precise results
            x = 0:h:dt_sim; y = zeros(1,length(x));
            % Tg linearization
            m = (Tg1-Tg0)/dt_sim; n = Tg0;
            x(1) = 0; y(1) = Twall0;
            y_dot =@(x,y)(4*d*(h_t/l*(m*x+n-y))^3/(3*(2*h_t/l*(m*x+n-y)*(y-Tamb)+h_t/l*(m*x+n-Tamb)^2))); % HEIN REDUCED EXPRESSION
            for i=1:length(x)-1
                k1 = y_dot(x(i),y(i));
                k2 = y_dot(x(i)+.5*h,y(i)+.5*k1*h);
                k3 = y_dot(x(i)+.5*h,y(i)+.5*k2*h);
                k4 = y_dot(x(i)+h,y(i)+k3*h);
                y(i+1) = y(i)+((k1+2*k2+2*k3+k4)/6)*h;
            end
            twall_next = y(length(x));
            % IMPROVEMENT: use ODE resolution
        end
        
        % Finite volume discretization of the grain
        function [FV_type,FV_length,FV_area] = MAIN_FV_discr (app,L_ch,L0_p,N_p,N_sim,~,Dint_p)
            N0_p = ceil(N_sim/N_p); % Rounds up and assigns the number of FV that correspond to each grain
            N_sim = N0_p*N_p; % Corrects the number of FV finally used (if necesary, might keep previous value)
            dL = L0_p/N0_p;
            dL_free = (L_ch-N_p*L0_p)/(2*N_p);
            FV_type_N = ones(1,N0_p); % N0_p
            FV_type_N(1) = 0; FV_type_N(length(FV_type_N)) = 0; % End elements
            FV_type = FV_type_N;
            
            if N_p > 1
                for i=1:N_p-1
                    FV_type = [FV_type FV_type_N]; % Copies the grain discretization for the total grains
                end
            else
            end
            
            FV_length_N = zeros(N0_p,2); % Position at which the FV starts (both core and end elements) 
            for i=1:N0_p
                FV_length_N(i,:) = [dL_free+(i-1)*dL, dL_free+i*dL];
            end                
            
            FV_length = FV_length_N;
            if N_p > 1 % Concatenates the FV_length
                for i=1:N_p-1
                    FV_length = [FV_length; dL_free+FV_length(length(FV_length),2)+FV_length_N];
                    % IMPROVEMENT: use N0_p·i instead of length(FV_length)
                end
            else    
            end
            
            FV_area = zeros(2,length(N_sim)); % Area in the interior of each finite volume
            for i=1:N_sim
                FV_area(2,i) = pi*Dint_p*dL; 
                % WARNING: makes invalid the stupid FV_area_old redefinition
                % IMPROVEMENT: no need to use such a long vector
            end
            
        end
        
        % The main function, adapted for the interface.
        function MAIN(app)
            app.results = [];
            
            % REPACKAGING
            % Ambient
            app.SIM(1) = app.Tamb.Value; % K 
            app.SIM(2) = app.Pamb.Value*10^5; % bar > Pa
            
            % Simulator
            app.SIM(3) = app.dt_sim.Value; % s
            app.SIM(4) = app.N_sim.Value; % -
            app.SIM(5) = app.n_sim.Value; % - / UNUSED
            app.SIM(6) = app.t_sim.Value; % s
            
            % Chamber - Geometry
            app.CH(1) = app.Dext_ch.Value*10^(-3); % mm > m
            app.CH(2) = app.Dint_ch.Value*10^(-3); % mm > m
            app.CH(3) = app.L_ch.Value*10^(-3); % mm > m
            app.CH(4) = app.Blk_union.Value; % N
            
            % Liner & Insulator
            app.CH(5) = app.t_li.Value*10^(-3); % mm > m
            %c_li and lambda_li are not accounted for yet
            app.CH(6) = app.t_ins.Value*10^(-3); % mm > m
            %c_ins and lambda_ins are not accounted for yet
            
            % Chamber - Material
            app.CH(7) = app.prop_ch(1)*10^9; % GPa > Pa (E_ch)
            app.CH(8) = app.prop_ch(2); % kg/m3 (dens_ch)
            app.CH(9) = app.prop_ch(3); % - (poisson_ch) / UNUSED
            app.CH(10) = app.prop_ch(4)+273; % ºC > K (Tmelt_ch)
            app.CH(11) = app.prop_ch(5); % W/mK (lambda_ch)
            
            yield_ch_T = app.yield_ch(1,:)+273; % ºC > K 
            yield_ch_v = app.yield_ch(2,:)*10^6; % MPa > Pa / REWRITE MAIN!!!
            
            uts_ch_T = app.uts_ch(1,:)+273; % ºC > K
            uts_ch_v = app.uts_ch(2,:)*10^6; % MPa > Pa / REWRITE MAIN!!!
            
            % Nozzle - Geometry
            app.NOZ(1) = app.Di_no.Value*10^(-3); % mm > m
            app.NOZ(2) = app.Dt_no.Value*10^(-3); % mm > m
            app.NOZ(3) = app.De_no.Value*10^(-3); % mm > m
            app.NOZ(4) = app.conv_no.Value; % º
            app.NOZ(5) = app.div_no.Value; % º
            app.NOZ(6) = app.Noz_union.Value; % N
            
            % Nozzle - Material
            app.NOZ(7) = app.prop_no(1)*10^9; % GPa > Pa (E_no)
            app.NOZ(8) = app.prop_no(2); % kg/m3 (dens_no)
            app.NOZ(9) = app.prop_no(3); % - (poisson_no) / UNUSED
            app.NOZ(10) = app.prop_no(4)+273; % ºC > K (Tmelt_no)
            app.NOZ(11) = app.prop_no(5); % W/mK (lambda_no)
            
            yield_no_T = app.yield_no(1,:)+273; % ºC > K
            yield_no_v = app.yield_no(2,:)*10^6; % MPa > Pa / REWRITE MAIN!!!
            
            uts_no_T = app.uts_no(1,:)+273; % ºC > K
            uts_no_v = app.uts_no(2,:)*10^6; % MPa > Pa / REWRITE MAIN!!!
            
            % Propellant - Geometry
            app.PROP(1) = app.Dext_p.Value*10^(-3); % mm > m
            app.PROP(2) = app.Dint_p.Value*10^(-3); % mm > m
            app.PROP(3) = app.L0_p.Value*10^(-3); % mm > m
            app.PROP(4) = app.N_p.Value; % -
            if app.Inh_out.Value == 1 % Outside inhibited
                app.PROP(5) = app.Inh_out.Value; % - (Outside inhibited)
            else
                app.PROP(5) = 2; % Like Input_Menu does
            end
            if app.Inh_end.Value == 1 % Ends inhibited
                app.PROP(6) = app.Inh_end.Value; % - (Ends inhibited)
            else
                app.PROP(6) = 2; % Like Input_Menu does
            end
                        
            % Propellant - Properties
            app.PROP(7) = app.prop_prop(1); % for mm/sec & MPa (ap)
            app.PROP(8) = app.prop_prop(2); % - (np)
            app.PROP(9) = app.prop_prop(3); % kg/m3 (dens_p)
            app.PROP(10) = app.prop_prop(4); % - (ratio_p)
            app.PROP(9) = app.PROP(9)*app.PROP(10); % Rewrites PROP(9) as the real density
            app.PROP(11) = app.prop_prop(5); % W/mK (lambda_p)
            app.PROP(12) = app.prop_prop(6); % J/kgK (c_p)
            app.PROP(13) = app.prop_prop(7)*10^(-3); % g/mol > kg/mol (Mw_g)
            app.PROP(14) = app.prop_prop(8); % J/kgK (Cv_g)
            app.PROP(15) = app.prop_prop(9); % - (k_g)
            app.PROP(16) = app.prop_prop(10); % Pa·s (mu_g)
            app.PROP(17) = app.prop_prop(11); % W/mK (lambda_g)
            app.PROP(18) = app.prop_prop(12); % K (T_flame_p)
            app.PROP(19) = app.prop_prop(13); % K (T_autoig_p)
            app.PROP(20) = app.prop_prop(14); % for SI units (alpha erosive burn)
            app.PROP(21) = app.prop_prop(15); % - (beta erosive burn)
            app.PROP(22) = app.prop_prop(16); % - (eff_comb)
            app.PROP(23) = app.prop_prop(17); % - (Xw - molar fraction water)
            
            %% Cracking type
            % {'No cracking', 'Microcracks', 'Bridge gaps (fatal)', 'Open gaps (fatal)'}
            if strcmp(app.Crack_type.Value,'Open gaps (fatal)')
                cr_factor = 8;
            elseif strcmp(app.Crack_type.Value,'Bridge gaps (fatal)')
                cr_factor = 4;
            elseif strcmp(app.Crack_type.Value,'Microcracks')
                cr_factor = 1.28;
            else
                cr_factor = 1;
            end
            
            
            %% Simulation time
            t_vec = 0:app.SIM(3):app.SIM(6); % The maximum duration evaluated. The simulation should stop before
            t_vec = t_vec.';
            
            %% FV Discretization
            [FV_type,FV_length,FV_area] = MAIN_FV_discr(app,app.CH(3),app.PROP(3),app.PROP(4),app.SIM(4),app.PROP(1),app.PROP(2)); 
            
            FV_dx = zeros(2,length(FV_type));
            FV_dx(2,:) = (FV_length(:,2)-FV_length(:,1)).';
            app.SIM(4) = length(FV_type);  % If discr has created more FV
            
            %% Ignition
            FV_ignited = zeros(2,length(FV_type));
            FV_Twall = zeros(2,length(FV_type));
            FV_Twall(2,:) = app.SIM(1);
            
            % Additional parameters definitions
            app.PROP(24) = 0.3; % in percentage (pi_k_p) HARDCODED, in truth it oscillates
            % IMPROVEMENT: not hardcoded
            app.PROP(24) = app.PROP(24)*0.01; % in per one (pi_k_p)
            app.PROP(25) = app.PROP(24)*(1-app.PROP(8)); % (sigma_p)
            app.PROP(26) = 8.314/(app.PROP(13)); % (r_g)
            
            app.PROP(7) = app.PROP(7)*exp((app.SIM(1)-293)*app.PROP(25)); % Reference temperature is considered to be ambient. HARDCODED.
            % Recalculation of ap depending on propellant initial temperature
            % IMPROVEMENT: use custom reference temperature
            
            app.CH(12) = pi/4*app.CH(2)^2*app.CH(3); % (V_ch)
            app.CH(13) = pi/4*(app.CH(2)^2-app.PROP(1)^2)*app.CH(3); % (V_liner_ins)
            % IMPROVEMENT: treat liner and insulator separately
           
            %% Losses
            % Divergence * Boundary layer * Reactions in nozzle * Two phase
            eff_tot = 0.5*(1+cos(app.NOZ(5)*pi/180))*0.985*0.995*0.95; % HARDCODED

            ero_f = 1.06; % 6% expected throat increase due to erosion
            dDt_no = (app.NOZ(2)*ero_f-app.NOZ(2))/t_vec(length(t_vec));
            % This is the the nozzle erosion in m/s. It should be noted, that this
            % consider that the burn lasts the full simulated time. This is incorrect
            % but it's impossible to estimate this ratio beforehand otherwise

            %% Vector initialization
            % Dimensions
            Dint_p_t = zeros(2,length(FV_type)); % internal diameter as a function of time
            Dt_no_t = zeros(length(t_vec),1); % throat erosion as a function of time

            % Mass flows 
            mdot_gen_el = zeros(1,length(FV_type));

            % Gas parameters
            T_g_el = zeros(2,length(FV_type)); % Element gas temperature

            % Heat exchange
            Qw_el = zeros(1,length(FV_type));

            % Results
            CF = zeros(length(t_vec),1);
            cstar = zeros(length(t_vec),1);
            T = zeros(length(t_vec),1);
            m_p = zeros(length(t_vec),1);
            Isp = zeros(length(t_vec),1);

            % Additional flags
            f_fail = false;

            %% Initial conditions
            P_ch = zeros(length(t_vec),1);
            T_ch = zeros(length(t_vec),1);
            P_ch(1) = app.SIM(2);
            T_ch(1) = app.SIM(1);

            Dint_p_t(2,:) = app.PROP(2);
            FV_Twall(2,:) = app.SIM(1);
            T_g_el(2,:) = app.SIM(1);
            Dt_no_t(1) = app.NOZ(2);
            
            ximp = mean((FV_length(1,1)+FV_length(1,2))/2); % default position - HARDCODED, for now
            % IMPROVEMENT: don't force impingement / make impingement relevant
            
            tic; % Initiates chronometer
            
            %% Pressure ratio calculation
            At_Ae = app.NOZ(2)^2/app.NOZ(3)^2;
            for PR = 1:0.01:1000
                ra = ((app.PROP(15)+1)/2)^(1/(app.PROP(15)-1))*(PR)^(-1/app.PROP(15))*sqrt((app.PROP(15)+1)/(app.PROP(15)-1)*(1-(PR)^((1-app.PROP(15))/app.PROP(15))));
                if abs(At_Ae-ra)/ra < 0.001
                    break;
                end
            end
            % This function finds the ratio between chamber pressure and outlet
            % pressure at the nozzle. This value is considered constant
            
            %% CALC: Transient Regime + Steady-State
            f_end = false;
            it = 1;
            k1 = [1 1 1 1 1 1];
            k2 = [0 0 0 0 0];
            % These are workarounds for ELEMENT SWITCH

            it1 = 1; it2 = 4;
            % These are workarounds to log into the console the results

            dt_save = app.SIM(3);    
            
            % INTRODUCE THE REST OF THE MAIN HERE
            while ~f_end && ~app.f_cancel
                % Loads previous time step as initial values    
                T_g_el(1,:) = T_g_el(2,:);
                FV_Twall(1,:) = FV_Twall(2,:);
                FV_area(1,:) = FV_area(2,:);
                FV_dx(1,:) = FV_dx(2,:);
                FV_ignited(1,:) = FV_ignited(2,:);
                Dint_p_t(1,:) = Dint_p_t(2,:);
                
                % Update of flame temperature
                T_flame = MAIN_flame_temp(app,app.PROP(18),app.PROP(22));
    
                if t_vec(it) <= app.IGN(2,length(app.IGN(1,:)))
                    mdot_ign = interp1(app.IGN(2,:), app.IGN(3,:), t_vec(it));
                else
                    mdot_ign = 0; 
                end  
                
                rdot_nom = cr_factor*(app.PROP(7)*(P_ch(it)*10^(-6))^(app.PROP(8))*10^(-3));
                % De Vielle equation. For pressures in MPa yields mm/s so they have to be transformed into m/s
                Ab_tot = sum(FV_area(1,FV_ignited(1,:)== 1)); % Full are currently burning
                mdot_gen = app.PROP(9)*Ab_tot*rdot_nom; % Generated mass flow
    
                if mdot_gen+mdot_ign >= MAIN_choked_nozzle(app,Dt_no_t(it),P_ch(it),T_ch(it),app.SIM(2),app.PROP(15),app.PROP(26),1)
                    % Choked nozzle if mass flow is high enough
                    mdot_noz = MAIN_choked_nozzle(app,Dt_no_t(it),P_ch(it),T_ch(it),app.SIM(2),app.PROP(15),app.PROP(26),1);
                else
      	            mdot_noz = mdot_gen+mdot_ign;
                end
                
                V_p = sum(pi/4*(app.PROP(1)^2-Dint_p_t(1,:).*Dint_p_t(1,:)).*FV_dx(1,:));
                m_p = V_p*app.PROP(9); % Total propellant mass
                V_free = app.CH(12) - V_p - app.CH(13); % Chamber without propellant or liner/insulator
                % Temperature differential with respects to time
                dT_dt = app.PROP(26)*T_ch(it)/(P_ch(it)*V_free*app.PROP(15))*(mdot_gen*app.PROP(15)*app.PROP(14)...
                *T_flame+mdot_ign*app.IGN(1,3)*app.IGN(1,4)*app.IGN(1,2)-mdot_noz*app.PROP(15)*app.PROP(14)*T_ch(it)-...
                T_ch(it)*(app.PROP(14)*mdot_gen+app.IGN(1,4)*mdot_ign-app.PROP(14)*mdot_noz));
                % Pressure differential with respects to time
                dP_dt =  app.PROP(26)*T_ch(it)/V_free*(mdot_gen+mdot_ign-mdot_noz)...
                +P_ch(it)/T_ch(it)*dT_dt-P_ch(it)/V_free*Ab_tot*rdot_nom;
            
            
                for i=1:length(FV_type)-k2(1) % XXXXX START OF ELEMENTWISE XXXXX
                    if i==1
                        mdot_in_el = mdot_ign;
                    else
                        mdot_in_el = mdot_out_el; % Carries over from the last iteration
                    end
        
                    if FV_type(i) == -1 % Jumps burnout elements
                        for j=1:length(FV_type)
                            if FV_dx(1,j) >= 0 % Elementwise resumed at the next non burnout element
                                i = j; 
                                break;
                            else
                            end
                        end  
                    end                    
                    
                    V_free_el = pi/4*Dint_p_t(1,i)^2*FV_dx(1,i);
                    dens_g_el = P_ch(it)/(app.PROP(26)*T_g_el(1,i)); % Gas density applying continuity
                    v_g_el = mdot_in_el/(dens_g_el*pi/4*Dint_p_t(1,i)^2);
        
                    if FV_ignited(1,i) == 1
                        rdot_nom_el = app.PROP(7)*(P_ch(it)*10^(-6))^(app.PROP(8))*10^(-3);  % ACTUALLY THE SAME AS RDOT_NOM(it)
                        rdot_ero_el = MAIN_erosive_burning(app,dens_g_el,v_g_el,Dint_p_t(1,i),app.PROP(20),app.PROP(21),rdot_nom_el,app.PROP(9));  
                        if rdot_ero_el < 0
                            rdot_ero_el = 0;
                        end
                        rdot_el = cr_factor*(rdot_nom_el + rdot_ero_el);
                        if ~FV_type(i) % Is end element
                            mdot_gen_el(i) = app.PROP(9)*FV_area(1,i)*rdot_el + (app.PROP(6)-1)*app.PROP(9)*pi/4*(app.PROP(1)^2-Dint_p_t(1,i)^2)*rdot_el;
                            % Adds the propellant generated by the non-core parts
                        else
                            mdot_gen_el(i) = app.PROP(9)*FV_area(1,i)*rdot_el;
                            % Only propellant generated by core
                        end  
                    else
                        mdot_gen_el(i) = 0; 
                    end
        
                    xel = FV_length(i,2); % Position of element end (used as characteristic length)
                    Re_g_el = dens_g_el*v_g_el*xel/app.PROP(16);
                    if (t_vec(it) < app.IGN(2,length(app.IGN(1,:)))) && (FV_ignited(1,i) == 0)
                        if xel < ximp  % Upstream
                            h_c_el = 3*Re_g_el^0.8*app.PROP(17)/Dint_p_t(1,i)*(0.0154+0.125*xel/ximp)*(Dint_p_t(1,i)^2/Dt_no_t(it)^2)^0.4;
                        else % Downstream
                            h_c_el = 3*Re_g_el^0.8*app.PROP(17)/Dint_p_t(1,i)*(0.022+0.11*exp(-0.5*(xel-ximp)/Dint_p_t(1,i)))*(Dint_p_t(1,i)^2/Dt_no_t(it)^2)^0.4;
                        end
                    elseif (t_vec(it) >= app.IGN(2,length(app.IGN(1,:)))) && (FV_ignited(1,i) == 0) % Igniter consumed
                        h_c_el = Re_g_el^0.8*app.PROP(17)/Dint_p_t(1,i)*(0.022+0.11*exp(-0.5*xel/Dint_p_t(1,i)))*(Dint_p_t(1,i)^2/Dt_no_t(it)^2)^0.4;
                    else % These equations are not contemplated when propellant is burning
                        h_c_el = 0;
                    end
                    
                    if FV_ignited(1,i) == 0
                        h_r_el = 0.25*app.sigma_rad*(T_g_el(1,i)^2+FV_Twall(1,i)^2)*(T_g_el(1,i)+FV_Twall(1,i));  
                        qw_el = (h_c_el+h_r_el)*(FV_Twall(1,i)-T_g_el(1,i));
                        Qw_el(i) = qw_el*FV_area(1,i);
                    end   
                    
                    if FV_ignited(1,i) == 1
                        dV_dt_el = FV_area(1,i)*rdot_el; % Like lost volume, will be zeros if propellant doesnt burn
                    else
                        dV_dt_el = 0;
                    end
        
                    mdot_stored_el = V_free_el/(app.PROP(26)*T_g_el(1,i))*(dP_dt-P_ch(it)/T_g_el(1,i)*dT_dt+P_ch(it)/V_free_el*dV_dt_el);
                    % Considers dT/dt to be constant
        
                    mdot_out_el = mdot_in_el + mdot_gen_el(i) - mdot_stored_el;
                    
                    if mdot_out_el < 0
                        mdot_out_el = 0;
                    end
                    % IMPROVEMENT: I'm goint to hell for this
        
                    dT_dt_el = app.PROP(26)*T_g_el(1,i)/(P_ch(it)*V_free_el*app.PROP(14))*...
                    (mdot_in_el*app.PROP(15)*app.PROP(14)*T_g_el(1,i)+mdot_gen_el(i)*app.PROP(15)*app.PROP(14)*T_flame-...
                    mdot_out_el*T_g_el(1,i)-T_g_el(1,i)*(app.PROP(14)*mdot_in_el+...
                    app.PROP(14)*mdot_gen_el(i)-app.PROP(14)*mdot_out_el))+Qw_el(i)/app.PROP(14);
        
                    dP_dt_el = app.PROP(26)*T_g_el(1,i)/V_free_el*(mdot_in_el+...
                    mdot_gen_el(i)-mdot_out_el)-P_ch(it)/V_free_el*dV_dt_el+...
                    P_ch(it)/T_g_el(1,i)*dT_dt_el;
        
                    if FV_ignited(1,i) == 0
                        % Euler for gas elemental temperature
                        T_g_el(2,i) = T_g_el(1,i) + dT_dt_el*app.SIM(3);
                        if T_g_el(2,i) > T_ch(it)
                            T_g_el(2,i) = T_ch(it); 
                            % As the element is part of the chamber it must never surpass
                                % chamber temperature
                        end
                    else
                        T_g_el(2,i) = T_ch(it);
                    end                    
                    
                    if FV_ignited(1,i) == 0 % Element is not ignited
                        diff_p = app.PROP(11)/(app.PROP(12)*app.PROP(9));
                        h_tot = h_r_el + h_c_el;
                        if (FV_Twall(1,i) - app.SIM(1)) > 10 % Ensure no infinite differential
                            dTwall_dt = 4*diff_p*(h_tot/app.PROP(11)*(T_g_el(1,i)-...
                            FV_Twall(1,i)))^3/(3*(2*h_tot/app.PROP(11)*(T_g_el(1,i)-...
                            FV_Twall(1,i))*(FV_Twall(1,i)-app.SIM(1))+...
                            h_tot/app.PROP(11)*(FV_Twall(1,i)-app.SIM(1))^2)); % RK4 ignores it, but no matter
                            FV_Twall(2,i) = MAIN_Twall_4th_order_RK (app,diff_p,h_c_el,h_r_el,app.PROP(11),app.SIM(1),FV_Twall(1,i),T_g_el(2,i),T_g_el(1,i),app.SIM(3)); % Uses RK4
                            % Calls 4th Order Runge-Kutta
                            % IMPROVEMENT: better 4th Order Runge-Kutta
                        else
                            dTwall_dt = -qw_el/(FV_area(1,i)*app.PROP(12)*app.PROP(9)*0.002*10^(-3));
                            FV_Twall(2,i) = FV_Twall(1,i) + dTwall_dt*app.SIM(3);
                            % Euler method for the wall temperature
                        end            
                        if FV_Twall(2,i) > app.PROP(19)
                            FV_ignited(2,i) = 1;
                            app.SIMLOG.Value = cat(1,app.SIMLOG.Value, ['Element ',num2str(i),' is ignited at t = ',num2str(round(t_vec(it),5)),' s']);
                            UI_Resize(app,0)
                            drawnow;
                            % Ignition message
                            FV_Twall(2,i) = T_flame;
                        else
                            FV_ignited(2,i) = 0;
                        end            
                    else % Element is ignited
                        FV_Twall(2,i) = T_flame;
                        FV_ignited(2,i) = 1; % Reafirms element is burning
                    end
        
                    % Geometry update
                    if FV_ignited(1,i) == 0
                        Dint_p_t(2,i) = Dint_p_t(1,i);
                        FV_dx(2,i) = FV_dx(1,i);
                        FV_area(2,i) = FV_area(1,i);                  
                    else
                        Dint_p_t(2,i) = Dint_p_t(1,i) + 2*rdot_el*app.SIM(3);
                        if FV_type(i) == 0 % End element
                        FV_dx(2,i) = FV_dx(1,i) - (app.PROP(6)-1)*rdot_el*app.SIM(3);
                            if FV_dx(2,i) < 0
                                FV_type(i) = -1; % Consumed element
                                % This code section is a workaround, but finds if the
                                % consumed end element is the first or final element 
                                % of a grain and changes the element type of the next
                                % or previous grain to an end grain and the current grain
                                % to consumed. The main difficult is to be able to work
                                % for multiple BATES grains. Even with this vast switch 
                                % makes it compatible only up to 4 BATES grain.
                                
                                % IMPROVEMENT: this is disguting Jesus Christ
                                
                                switch i % End element is the first of a grain
                                    case k1(1)
                                        k1(1)=k1(1)+1; FV_type(i+1) = 0;
                                        mdot_gen_el(i) = 0; Qw_el(i) = 0;
                                    case app.SIM(4)/app.PROP(4)+k1(2)
                                        k1(2)=k1(2)+1; FV_type(i+1) = 0;
                                        mdot_gen_el(i) = 0; Qw_el(i) = 0;
                                    case 2*app.SIM(4)/app.PROP(4)+k1(3)
                                        k1(3)=k1(3)+1; FV_type(i+1) = 0;
                                        mdot_gen_el(i) = 0; Qw_el(i) = 0;
                                    case 3*app.SIM(4)/app.PROP(4)+k1(4)
                                        k1(4)=k1(4)+1; FV_type(i+1) = 0;
                                        mdot_gen_el(i) = 0; Qw_el(i) = 0;
                                    case 4*app.SIM(4)/app.PROP(4)+k1(5)
                                        k1(5)=k1(5)+1; FV_type(i+1) = 0;
                                        mdot_gen_el(i) = 0; Qw_el(i) = 0;
                                    case 5*app.SIM(4)/app.PROP(4)+k1(6)
                                        k1(6)=k1(6)+1; FV_type(i+1) = 0;
                                        mdot_gen_el(i) = 0; Qw_el(i) = 0;
                                    otherwise
                                end
                    
                                switch i % End element is the last of a grain
                                    case app.SIM(4)/app.PROP(4)-k2(1)
                                        k2(1)=k2(1)+1; FV_type(i-1) = 0;
                                        mdot_gen_el(i) = 0; Qw_el(i) = 0;
                                    case 2*app.SIM(4)/app.PROP(4)-k2(2)
                                        k2(2)=k2(2)+1; FV_type(i-1) = 0;
                                        mdot_gen_el(i) = 0; Qw_el(i) = 0;
                                    case 3*app.SIM(4)/app.PROP(4)-k2(3)
                                        k2(3)=k2(3)+1; FV_type(i-1) = 0;
                                        mdot_gen_el(i) = 0; Qw_el(i) = 0;
                                    case 4*app.SIM(4)/app.PROP(4)-k2(4)
                                        k2(4)=k2(4)+1; FV_type(i-1) = 0;
                                        mdot_gen_el(i) = 0; Qw_el(i) = 0;
                                    case 5*app.SIM(4)/app.PROP(4)-k2(5)
                                        k2(5)=k2(5)+1; FV_type(i-1) = 0;
                                        mdot_gen_el(i) = 0; Qw_el(i) = 0;
                                    otherwise
                                end
                    
                            else
                            end
                            FV_area(2,i) = pi*Dint_p_t(2,i)*FV_dx(2,i) - pi*Dint_p_t(2,i)*rdot_el*app.SIM(3) - pi/4*(Dint_p_t(2,i)^2-Dint_p_t(2,i)^2);
                             
                            % An end element losses area due to burning from the ends, irrelevant for low N
                        elseif FV_type(i) == -1  % Consumed element
                        % Is left unused
                        else
                            FV_dx(2,i) = FV_dx(1,i);
                            FV_area(2,i) = pi*Dint_p_t(2,i)*FV_dx(2,i);           
                        end  
                    end                                
                end % XXXXX END OF ELEMENTWISE XXXXX 
                
                Q_tot = sum(Qw_el); % Summation of total lost heat
                mdot_gen = sum(mdot_gen_el); % Summation of total generated mass flow
    
                if 1.001*(mdot_gen+mdot_ign) >= MAIN_choked_nozzle(app,Dt_no_t(it),P_ch(it),T_ch(it),app.SIM(2),app.PROP(15),app.PROP(26),1)
                    % Factor 1.001 is a workaround to avoid too close choked condition
                    % Choked nozzle
                    mdot_noz = MAIN_choked_nozzle(app,Dt_no_t(it),P_ch(it),T_ch(it),app.SIM(2),app.PROP(15),app.PROP(26),1);
                else
                    % Unchoked nozzle
                    mdot_noz = MAIN_choked_nozzle(app,Dt_no_t(it),P_ch(it),T_ch(it),app.SIM(2),app.PROP(15),app.PROP(26),0);
                end
    
                dT_dt = app.PROP(26)*T_ch(it)/(P_ch(it)*V_free*app.PROP(14))*(mdot_gen*app.PROP(15)*app.PROP(14)*T_flame+...
                mdot_ign*app.IGN(1,3)*app.IGN(1,4)*app.IGN(1,2)-mdot_noz*app.PROP(15)*app.PROP(14)*T_ch(it)-T_ch(it)*(app.PROP(14)*mdot_gen+...
                app.IGN(1,4)*mdot_ign-app.PROP(14)*mdot_noz)) + Q_tot/app.PROP(14);
                dP_dt = app.PROP(26)*T_ch(it)/V_free*(mdot_gen+mdot_ign-mdot_noz)+P_ch(it)/T_ch(it)*dT_dt-P_ch(it)*mdot_gen/(V_free*app.PROP(9));
  
                T_ch(it+1) = T_ch(it)+dT_dt*app.SIM(3);
                P_ch(it+1) = P_ch(it)+dP_dt*app.SIM(3);
                % Euler method for both chamber pressure and temperature
    
                if T_ch(it+1) > T_flame
                    T_ch(it+1) = T_flame; % Chamber cannot be hotter than the flame
                end 
    
                Dt_no_t(it+1) = Dt_no_t(it) + 0.7893*app.PROP(23)*(P_ch(it+1)/10^9)^(0.8)*10^(-3)*app.SIM(3) + dDt_no*app.SIM(3); 
                % Nozzle diameter erosion due to model + experimental 6% diameter increase
  
                Pe = P_ch(it)/PR;   
                CF(it) = eff_tot*sqrt((2*app.PROP(15)^2)/(app.PROP(15)-1)*(2/(app.PROP(15)+1))^((app.PROP(15)+1)/(app.PROP(15)-1))*(1-(1/PR)^((app.PROP(15)-1)/app.PROP(15)))) + (Pe-app.SIM(2))*app.NOZ(3)^2/(P_ch(it)*Dt_no_t(it)^2);
                if CF(it) < 0
                    CF(it) = 0;
                end
                if mdot_gen > 0
                    cstar(it) = app.PROP(22)*P_ch(it)*pi/4*Dt_no_t(it)^2/(mdot_gen);
                    % Chamber property
                else
                    cstar(it) = 0;
                end
                if cstar(it) > 10^10
                    cstar(it) = 0;
                end
    
                T(it) = CF(it)*P_ch(it)*pi/4*Dt_no_t(it)^2;
    
                Isp(it) = cstar(it)*CF(it)/9.81;
    
                % Provides output to the user each 10 ms for the first 200 ms and each
                % 100 ms after that
                if t_vec(it) <= 0.2
                    if abs(t_vec(it)-0.01*(it1-1)) < 10^(-10)
                        fullmessage = ['Results at t = ',num2str(round(t_vec(it),2)),' s / Chamber pressure = ',num2str(round(P_ch(it)*10^(-5),2)),' bar / Chamber temperature = ',num2str(round(T_ch(it),2)),' K / Propellant mass left = ',num2str(round(m_p,3)),' kg'];                       
                        app.SIMLOG.Value = cat(1,app.SIMLOG.Value,fullmessage);
                        UI_Resize(app,0)
                        it1 = it1 + 1;
                    end
                elseif t_vec(it) > 0.2
                    if abs(t_vec(it)-0.1*(it2-1)) < 10^(-10)
                        fullmessage = ['Results at t = ',num2str(round(t_vec(it),2)),' s / Chamber pressure = ',num2str(round(P_ch(it)*10^(-5),2)),' bar / Chamber temperature = ',num2str(round(T_ch(it),2)),' K / Propellant mass left = ',num2str(round(m_p,3)),' kg'];
                        app.SIMLOG.Value = cat(1,app.SIMLOG.Value,fullmessage);
                        UI_Resize(app,0)
                        it2 = it2 + 1;
                    end      
                else
                end
                
                % Input app, Tamb, yield_ch_T, yield_ch --> if this fails uts would too, so there's no need to use it
                if P_ch(it) > MAIN_stress_interpolation(app,app.SIM(1),yield_ch_T,yield_ch_v)*(app.CH(1)-app.CH(2))/app.CH(1)
                    app.SIMLOG.Value = cat(1,app.SIMLOG.Value,'----------------------------------',['FATAL ERROR: ', 'failure of the combustion chamber.']);
                    UI_Resize(app,1)
                    f_fail = true;
                    break;
                    % IMPROVEMENT: heating chamber model
                elseif P_ch(it)*pi/4*app.CH(2)^2 > app.CH(4)
                    app.SIMLOG.Value = cat(1,app.SIMLOG.Value,'----------------------------------',['FATAL ERROR: ', 'failure of the bulkhead.']);
                    UI_Resize(app,1)
                    f_fail = true;
                    break;
                elseif P_ch(it)*pi/4*(app.CH(2)^2-Dt_no_t(it)^2) > app.NOZ(6) || T_ch(it) > app.NOZ(10)
                    app.SIMLOG.Value = cat(1,app.SIMLOG.Value,'----------------------------------',['FATAL ERROR: ', 'failure of the nozzle.']);
                    UI_Resize(app,1)
                    f_fail = true;
                    break;
                else   
                end
    
                if (m_p < 10^(-6)) || (any(app.PROP(1)-Dint_p_t(2,:)<0))
                    % Stops simulation for consumed mass or elements regression entering
                    % the negative
                    it_off = it;
                    break;
                end
    
                it=it+1;                    
            end


            %% CALC: Tail-off
            if ~app.f_cancel
                while (P_ch(it-1) > app.SIM(2)) && ~f_fail
                    P_ch(it) = P_ch(it_off)*exp(-(app.PROP(26)*T_ch(it_off)*pi/4*Dt_no_t(it_off)^2)/(V_free*cstar(it_off))*app.SIM(3)*(it-it_off));
                    % Nozzle erosion is negligible here
                    Pe(it) = P_ch(it)/PR;
                    CF(it) = eff_tot*sqrt((2*app.PROP(15)^2)/(app.PROP(15)-1)*(2/(app.PROP(15)+1))^((app.PROP(15)+1)/(app.PROP(15)-1))*(1-(1/PR)^((app.PROP(15)-1)/app.PROP(15)))) + (Pe(it)-app.SIM(2))*app.NOZ(3)^2/(P_ch(it)*Dt_no_t(it_off)^2);
                    T(it) = eff_tot*CF(it)*P_ch(it)*pi/4*Dt_no_t(it_off)^2;
                    Isp(it) = cstar(it_off)*CF(it)/9.81;
                    if T(it) < 0 || CF(it) < 0
                        CF(it) = 0;
                        T(it) = 0;
                    end 
                    it = it+1;
                end
            end
            % IMPROVEMENT: ensure tail-off functions
            app.SIMLOG.Value = cat(1,app.SIMLOG.Value,'-------------------------------------------------------------------------');
            
            if app.f_cancel
                app.SIMLOG.Value = cat(1,app.SIMLOG.Value,'RUN WAS ABORTED.');
                app.SIMLOG.Value = cat(1,app.SIMLOG.Value,['Elapsed time is ',num2str(toc),' seconds.']);
                app.f_cancel = false;
            else
                app.SIMLOG.Value = cat(1,app.SIMLOG.Value,'Run is finished.');
                app.SIMLOG.Value = cat(1,app.SIMLOG.Value,['Elapsed time is ',num2str(toc),' seconds.']);                
            end

            UI_Resize(app,1);
            
            
            %% OUTPUTS
            if ~f_fail && ~app.f_cancel
                cont = 0;
                for j=1:it-1
                    if it <= 50000 % Saves all results for low amount of data points
                        app.results(2,cont+2) = t_vec(j);
                        app.results(3,cont+2) = P_ch(j);
                        app.results(4,cont+2) = T(j);
                        app.results(5,cont+2) = CF(j);
                        cont = cont + 1;        
                    elseif t_vec(j) <= 0.2
                        if rem(j,10) == 0 % Saved 1/10 data
                            app.results(2,cont+2) = t_vec(j+1);
                            app.results(3,cont+2) = P_ch(j+1);
                            app.results(4,cont+2) = T(j+1);
                            app.results(5,cont+2) = CF(j+1);
                            cont = cont + 1;             
                        elseif j == 1
                            app.results(2,cont+2) = t_vec(j);
                            app.results(3,cont+2) = P_ch(j);
                            app.results(4,cont+2) = T(j);
                            app.results(5,cont+2) = CF(j);
                            cont = cont + 1;    
                        else
                        end               
                    else
                        if rem(j,100) == 0 % Saves 1/100 data
                            app.results(2,cont+2) = t_vec(j+1);
                            app.results(3,cont+2) = P_ch(j+1);
                            app.results(4,cont+2) = T(j+1);
                            app.results(5,cont+2) = CF(j+1);
                            cont = cont + 1;             
                        else
                        end
                    end
    
                end
                app.results(1,:) = zeros(1,length(app.results(2,:)));
                app.results(1,2) = length(app.results(1,:))-1;
                app.results(6,:) = zeros(1,length(app.results(2,:)));
                app.results(6,2) = sum(T(1:it)*app.SIM(3));
                
                %Isp_av = results(6,2)/(9.81*pi/4*(PROP(1)^2-PROP(2)^2)*PROP(3)*PROP(4)*PROP(9));
                
                filename = 'output.csv';
                %fullname = cat(2,'C:\Program Files\PropSIM\application\',filename);
                writematrix(app.results,filename);
                
                app.f_sim = true; % Simulation is finished

            else
            end
     
        end
    end
    
    methods (Access = public)
        
        function UI_editmat_update(app,mat_save,newname,par)
            % No need to use two different functions
            if par
                app.prop_ch = mat_save(2,1:mat_save(1,1));
                app.yield_ch = mat_save(4:5,1:mat_save(3,1));
                app.uts_ch = mat_save(7:8,1:mat_save(6,1));
                app.Mat_ch_label.Text = cat(2,'Material used: ',newname);
            else
                app.prop_no = mat_save(2,1:mat_save(1,1));
                app.yield_no = mat_save(4:5,1:mat_save(3,1));
                app.uts_no = mat_save(7:8,1:mat_save(6,1));
                app.Mat_no_label.Text = cat(2,'Material used: ',newname);
            end            
        end
        
        function UI_editprop_update(app,prop_save,newname)
            app.prop_prop = prop_save(2,1:prop_save(1,1));
            app.Prop_label.Text = cat(2,'Propellant used: ',newname);
        end
      
        
        function UI_editign_update(app,ign_save,newname)
            app.IGN = ign_save;
            app.Ign_label.Text = cat(2,'Igniter used: ',newname);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            drawnow;
            app.UIFigure.WindowState = 'maximized';
        end

        % Button pushed function: OPENCase
        function UI_opencase(app, event)
            %%% THIS FUNCTION READS THE PRESET AND WRITES INSIDE UI
            %[file,path] = uigetfile('.\Libraries\Case\*.csv');
            [file,path] = uigetfile('C:\Program Files\PropSIM\application\Libraries\Case\*.csv');
            %if isnumeric(file)
            %    [file,path] = uigetfile('.\Libraries\Case\*.csv');
            %end  
            fullpath = cat(2,path,file);
            preset_data = readtable(fullpath);
            
            % DATA UNPACKING - For UI elements
            % Ambient
            app.Tamb.Value = preset_data{1,1};
            app.Pamb.Value = preset_data{1,2};
            
            % Chamber
            app.Dext_ch.Value = preset_data{2,1};
            app.Dint_ch.Value = preset_data{2,2};
            app.L_ch.Value = preset_data{2,3};
            app.Blk_union.Value = preset_data{2,4};
            
            % Propellant
            app.Dext_p.Value = preset_data{10,1};
            app.Dint_p.Value = preset_data{10,2};
            app.L0_p.Value = preset_data{10,3};
            app.N_p.Value = preset_data{10,4};
            switch preset_data{10,5}
                case 1
                    app.Inh_out.Value = true;
                case 2
                    app.Inh_out.Value = false;
                otherwise
            end
            switch preset_data{10,6}
                case 1
                    app.Inh_end.Value = true;
                case 2
                    app.Inh_end.Value = false;
                otherwise
            end
            
            % Nozzle
            app.Di_no.Value = preset_data{12,1};
            app.Dt_no.Value = preset_data{12,2};
            app.De_no.Value = preset_data{12,3};
            app.conv_no.Value = preset_data{12,4};
            app.div_no.Value = preset_data{12,5};
            app.Noz_union.Value = preset_data{12,6};
            
            % Liner
            app.t_li.Value = preset_data{20,1};
            app.c_li.Value = preset_data{20,2};
            app.lambda_li.Value = preset_data{20,3};
            
            % Insulator
            app.t_ins.Value = preset_data{21,1};
            app.c_ins.Value = preset_data{21,2};
            app.lambda_ins.Value = preset_data{21,3};
            
            % Simulation
            app.dt_sim.Value = preset_data{22,1};
            app.N_sim.Value = preset_data{22,2};
            app.n_sim.Value = preset_data{22,3};
            app.t_sim.Value = preset_data{22,4};
            
            
            % DATA UNPACKING - For fixed properties
            % Chamber
            app.prop_ch = preset_data{3,1:5}; % UPDATE IF LENGTH PROPERTIES CHANGE
            app.yield_ch = preset_data{5:6,1:preset_data{4,1}};
            app.uts_ch = preset_data{8:9,1:preset_data{7,1}};
            
            % Nozzle
            app.prop_no = preset_data{13,1:5}; % UPDATE IF LENGTH PROPERTIES CHANGE
            app.yield_no = preset_data{15:16,1:preset_data{14,1}};
            app.uts_no = preset_data{18:19,1:preset_data{17,1}};
            
            % Propellant
            app.prop_prop = preset_data{11,1:17}; % UPDATE IF LENGTH PROPERTIES CHANGE
            
            
            % Update the labels
            app.Loaded_name_case.Text = file;
            app.Mat_ch_label.Text = cat(2,'Material given by ',file);
            app.Mat_no_label.Text = cat(2,'Material given by ',file);
            app.Prop_label.Text = cat(2,'Propellant given by ',file);
            
            UI_Resize(app,1);
            
        end

        % Button pushed function: OPENIgn
        function UI_openign(app, event)
            %%%% READING THE IGNITER PRESET
            %[file,path] = uigetfile('.\Libraries\Ign\*.csv');
            [file,path] = uigetfile('C:\Program Files\PropSIM\application\Libraries\Ign\*.csv');
            %if isnumeric(file)
            %    [file,path] = uigetfile('C:\Program Files\PropSIM\application\Libraries\Ign\*.csv');
            %end              
            fullpath = cat(2,path,file);
            ign_read = readtable(fullpath);
            
            app.IGN = zeros(3,ign_read{1,1});
            app.IGN(1,2) = ign_read{1,2};
            app.IGN(1,3) = ign_read{1,3};
            app.IGN(1,4) = ign_read{1,4};
            app.IGN(1,1) = ign_read{1,1};
            
            app.IGN(2,1:app.IGN(1,1)) = ign_read{2,1:ign_read{1,1}};
            app.IGN(3,1:app.IGN(1,1)) = ign_read{3,1:ign_read{1,1}};
            
            % Update the label
            app.Ign_label.Text = cat(2,'Igniter used: ',file);
            
            UI_Resize(app,1);
        end

        % Button pushed function: OPENMat_ch
        function UI_openmat_ch(app, event)
            %[file,path] = uigetfile('.\Libraries\Mat\*.csv');
            [file,path] = uigetfile('C:\Program Files\PropSIM\application\Libraries\Mat\*.csv');
            %if isnumeric(file)
            %    [file,path] = uigetfile('C:\Program Files\PropSIM\application\Libraries\Mat\*.csv');
            %end            
            fullpath = cat(2,path,file);
            mat_load = readtable(fullpath);
            
            % Unpacking
            app.prop_ch = mat_load{2,1:mat_load{1,1}};
            app.yield_ch = mat_load{4:5,1:mat_load{3,1}};
            app.uts_ch = mat_load{7:8,1:mat_load{6,1}};
            
            % Update the label
            app.Mat_ch_label.Text = cat(2,'Material used: ',file);
            
            UI_Resize(app,1);
        end

        % Button pushed function: OPENMat_no
        function UI_openmat_no(app, event)
            %[file,path] = uigetfile('.\Libraries\Mat\*.csv');
            [file,path] = uigetfile('C:\Program Files\PropSIM\application\Libraries\Mat\*.csv');
            
            %if isnumeric(file)
            %    [file,path] = uigetfile('C:\Program Files\PropSIM\application\Libraries\Mat\*.csv');
            %end   
            fullpath = cat(2,path,file);
            mat_load = readtable(fullpath);
            
            % Unpacking
            app.prop_no = mat_load{2,1:mat_load{1,1}};
            app.yield_no = mat_load{4:5,1:mat_load{3,1}};
            app.uts_no = mat_load{7:8,1:mat_load{6,1}};
            
            
            % Update the label
            app.Mat_no_label.Text = cat(2,'Material used: ',file);
            
            UI_Resize(app,1);
        end

        % Button pushed function: OPENProp
        function UI_openprop(app, event)
            %[file,path] = uigetfile('.\Libraries\Prop\*.csv');
            [file,path] = uigetfile('C:\Program Files\PropSIM\application\Libraries\Prop\*.csv');
            %if isnumeric(file)
            %    [file,path] = uigetfile('C:\Program Files\PropSIM\application\Libraries\Prop\*.csv');
            %end            
            fullpath = cat(2,path,file);
            prop_load = readtable(fullpath);
            
            % Unpacking
            app.prop_prop = prop_load{2,1:prop_load{1,1}};
            
            % Update the label
            app.Prop_label.Text = cat(2,'Propellant used: ',file);
            
            UI_Resize(app,1);
        end

        % Button pushed function: LOADCURVESButton
        function UI_loadcurves(app, event)
            %[file,path] = uigetfile('.\Saved curves\*.csv');
            [file,path] = uigetfile('C:\Program Files\PropSIM\application\Saved curves\*.csv');
            %if isnumeric(file)
            %    [file,path] = uigetfile('C:\Program Files\PropSIM\application\Saved curves\*.csv');
            %end
            
            fullpath = cat(2,path,file);
            prop_comp = readtable(fullpath);
            
            % Matrix enlargement along the y axis
            if prop_comp{1,2} > length(app.loaded_curves(1,1,app.loaded_curves_index)) % Loaded curve is longer than current loaded curve size
                loaded_curves_local = zeros(6,prop_comp{1,2},length(app.loaded_curves(1,1,:))); % Local longer matrix
                for i=1:length(app.loaded_curves(1,1,:)) % Iterate through all saved curves
                    if i < app.loaded_curves_index % Curve already worked with
                        loaded_curves_local(1:6,1:app.loaded_curves(1,1,i),i) = app.loaded_curves(1:6,1:app.loaded_curves(1,1,i),i); % Copies data from all saved curves
                    elseif i == app.loaded_curves_index
                        loaded_curves_local(1:6,1:prop_comp{1,2},i) = prop_comp{1:6,2:prop_comp{1,2}+1};
                    else
                        % Empty curve, you can leave the zeros be
                    end
                    
                end
                app.loaded_curves = loaded_curves_local;
            else % Loaded curve shorter than current loaded curve size
                app.loaded_curves(1:6,1:prop_comp{1,2},app.loaded_curves_index) = prop_comp{1:6,2:prop_comp{1,2}+1};
            end
            
            
            app.loaded_curves_list(app.loaded_curves_index) = file;
            
            if app.loaded_curves(3,1,app.loaded_curves_index) > 1000 % Pressure is in Pa
                app.loaded_curves(3,:,app.loaded_curves_index) = app.loaded_curves(3,:,app.loaded_curves_index)*10^(-5);
            end
            app.loaded_curves(isnan(app.loaded_curves))=0; % Purge NaN
            
            if app.loaded_curves_index == 1 % First curve
                app.List_curves.Value = cat(1,"LOADED CURVES:",file);
            else % All other curves
                app.List_curves.Value = cat(1,app.List_curves.Value,file);
            end
            
            app.loaded_curves_index = app.loaded_curves_index+1; % Update the number of curves
            
            
            UI_Resize(app,1);
        end

        % Button pushed function: PLOTButton
        function UI_PLOT(app, event)
            if app.f_sim % The simulation has already been ran
                t_vec = app.results(2,2:app.results(1,2)+1);
                P_vec = app.results(3,2:app.results(1,2)+1)*10^(-5);
                T_vec = app.results(4,2:app.results(1,2)+1);
                CF_vec = app.results(5,2:app.results(1,2)+1);
                It = app.results(6,2);
                It = round(It,1);                
                
                if app.loaded_curves_index == 1
                    % Only use results curve
                    app.PlotPanel.AutoResizeChildren = 'off';
                    
                    t = tiledlayout(app.PlotPanel,2,3,'Padding', 'none', 'TileSpacing', 'compact');                  
                    
                    ax1 = nexttile(t); % Left
                    plot(ax1,t_vec,P_vec);
                    title(ax1,'Chamber pressure');
                    xlabel(ax1,['Time (s)']);
                    ylabel(ax1,['Chamber pressure (bar)']);                      
                    
                    ax2 = nexttile(t); % Middle
                    plot(ax2,t_vec,T_vec);
                    title(ax2,'Thrust');
                    xlabel(ax2,['Time (s)']);
                    ylabel(ax2,['Thrust (N)']); 
                    
                    ax3 = nexttile(t); % Right
                    plot(ax3,t_vec,CF_vec);
                    title(ax3,'Thrust coefficient');
                    xlabel(ax3,['Time (s)']);
                    ylabel(ax3,['Thrust coefficient']);
                    
                    app.PlotPanel.Title = ['PropSim simulation results (It = ',num2str(It),' Ns)'];                  
                    
                elseif app.loaded_curves_index == 2
                    % One result curve vs one loaded curve direct comparison
                    t_vec_comp = app.loaded_curves(2,1:app.loaded_curves(1,1),1);
                    P_vec_comp = app.loaded_curves(3,1:app.loaded_curves(1,1),1);
                    T_vec_comp = app.loaded_curves(4,1:app.loaded_curves(1,1),1);
                    CF_vec_comp = app.loaded_curves(5,1:app.loaded_curves(1,1),1);
                    It_comp = app.loaded_curves(6,1,1);                   
                    
                    rel_error = abs(It-It_comp)/It_comp*100;
                    
                    app.PlotPanel.AutoResizeChildren = 'off';                
                    t = tiledlayout(app.PlotPanel,2,3,'Padding', 'none', 'TileSpacing', 'compact'); 
                    
                    ax1 = nexttile(t); % Left upper
                    plot(ax1,t_vec,T_vec);
                    hold(ax1,'on')
                    plot(ax1,t_vec_comp,T_vec_comp);    
                    title(ax1,'Thrust');
                    xlabel(ax1,['Time (s)']);
                    ylabel(ax1,['Thrust (N)']);
                    l1 = ['PropSim: It = ',num2str(It),' Ns'];
                    l2 = cat(2,app.loaded_curves_list{1},': It = ',num2str(It_comp),' Ns');
                    legend(ax1,l1,l2);
                    
                    ax2 = nexttile(t); % Middle upper  
                    plot(ax2,t_vec,CF_vec);
                    hold(ax2,'on')
                    plot(ax2,t_vec_comp,CF_vec_comp);
                    title(ax2,'Thrust coefficient');
                    xlabel(ax2,['Time (s)']);
                    ylabel(ax2,['Thrust coefficient']);
                    
                    ax3 = nexttile(t); % Right upper   
                    plot(ax3,t_vec,P_vec);
                    hold(ax3,'on')
                    plot(ax3,t_vec_comp,P_vec_comp);
                    title(ax3,'Chamber pressure');
                    xlabel(ax3,['Time (s)']);
                    ylabel(ax3,['Chamber pressure (bar)']);
                    
                    
                    % Shorten all comparison vectors to pressure vector size
                    P_vec_comp = P_vec_comp(P_vec_comp ~= 0);
                    T_vec_comp = T_vec_comp(1:length(P_vec_comp));
                    CF_vec_comp = CF_vec_comp(1:length(P_vec_comp));
                    t_vec_comp = t_vec_comp(1:length(P_vec_comp));
                    
                    if t_vec(length(t_vec)) > t_vec_comp(length(t_vec_comp))
                        % Simulation curve longer than reference curve
                        t_vec_corr = t_vec_comp;
                        for i = 1:length(t_vec_corr)
                            % You correct the curve given by PropSim to follow reference
                            P_vec_corr(i) = interp1(t_vec,P_vec,t_vec_corr(i));
                            T_vec_corr(i) = interp1(t_vec,T_vec,t_vec_corr(i)); 
                            CF_vec_corr(i) = interp1(t_vec,CF_vec,t_vec_corr(i)); 
            
                            err_P(i) = abs(P_vec_corr(i) - P_vec_comp(i))/P_vec_comp(i)*100;
                            err_T(i) = abs(T_vec_corr(i) - T_vec_comp(i))/T_vec_comp(i)*100;
                            err_CF(i) = abs(CF_vec_corr(i) - CF_vec_comp(i))/CF_vec_comp(i)*100;
            
                            if err_P(i) > 100 || isnan(err_P(i))
                                err_P(i) = 100;
                            elseif err_T(i) > 100 || isnan(err_T(i))
                                err_T(i) = 100;
                            elseif err_CF(i) > 100 || isnan(err_CF(i))
                                err_CF(i) = 100;
                            else
                            end
                        end   
                    else % Reference curve longer than simulation curve
                        t_vec_corr = t_vec;
                        for i = 1:length(t_vec_corr)
                            % You correct the reference to follow PropSim
                            P_vec_corr(i) = interp1(t_vec_comp,P_vec_comp,t_vec_corr(i)); % Resizing sim curve as reference
                            T_vec_corr(i) = interp1(t_vec_comp,T_vec_comp,t_vec_corr(i));
                            CF_vec_corr(i) = interp1(t_vec_comp,CF_vec_comp,t_vec_corr(i));
            
                            err_P(i) = abs(P_vec_corr(i) - P_vec(i))/P_vec(i)*100;
                            err_T(i) = abs(T_vec_corr(i) - T_vec(i))/T_vec(i)*100;
                            err_CF(i) = abs(CF_vec_corr(i) - CF_vec(i))/CF_vec(i)*100;
            
                            if err_P(i) > 100 || isnan(err_P(i))
                                err_P(i) = 100;
                            elseif err_T(i) > 100 || isnan(err_T(i))
                                err_T(i) = 100;
                            elseif err_CF(i) > 100 || isnan(err_CF(i))
                                err_CF(i) = 100;
                            else
                            end
                        end
                    end                    
                    
                    ax4 = nexttile(t); % Left lower
                    plot(ax4,t_vec_corr,err_T);
                    xlabel(ax4,['Time (s)']);
                    ylabel(ax4,['Relative error thrust (%)']);
                    ylim(ax4,[0 100]);
                    xlim(ax4,[0 t_vec_corr(length(t_vec_corr))+0.5]); 
                    
                    ax5 = nexttile(t); % Middle lower
                    plot(ax5,t_vec_corr,err_CF);
                    xlabel(ax5,['Time (s)']);
                    ylabel(ax5,['Relative error thrust coefficient (%)']);
                    ylim(ax5,[0 100]);
                    xlim(ax5,[0 t_vec_corr(length(t_vec_corr))+0.5]);  
                    
                    ax6 = nexttile(t); % Right lower
                    plot(ax6,t_vec_corr,err_P);
                    xlabel(ax6,['Time (s)']);
                    ylabel(ax6,['Relative error pressure (%)']);
                    ylim(ax6,[0 100]);
                    xlim(ax6,[0 t_vec_corr(length(t_vec_corr))+0.5]);                    
                     
                    app.PlotPanel.Title = ['PropSim results (It = ',num2str(It),') compared to ',app.loaded_curves_list{1},' curve (It = ',num2str(It_comp),') (relative error It = ',num2str(rel_error),'%)'];
                    
                else
                    % One result curve and two or more loaded curves
                    app.PlotPanel.AutoResizeChildren = 'off';
                    
                    t = tiledlayout(app.PlotPanel,2,2,'Padding', 'none', 'TileSpacing', 'compact');                      
                    
                    ax1 = nexttile(t); % Left upper
                    plot(ax1,t_vec,T_vec);
                    title(ax1,'Thrust');
                    xlabel(ax1,['Time (s)']);
                    ylabel(ax1,['Thrust (N)']); 
    
                    ax2 = nexttile(t); % Right upper  
                    plot(ax2,t_vec,CF_vec);
                    title(ax2,'Thrust coefficient');
                    xlabel(ax2,['Time (s)']);
                    ylabel(ax2,['Thrust coefficient']);
    
                    ax3 = nexttile(t); % Left lower   
                    plot(ax3,t_vec,P_vec);
                    title(ax3,'Chamber pressure');
                    xlabel(ax3,['Time (s)']);
                    ylabel(ax3,['Chamber pressure (bar)']);
                    
                    
                    for i=1:app.loaded_curves_index-1
                        t_vec_comp = app.loaded_curves(2,1:app.loaded_curves(1,1,i),i);
                        P_vec_comp = app.loaded_curves(3,1:app.loaded_curves(1,1,i),i);
                        T_vec_comp = app.loaded_curves(4,1:app.loaded_curves(1,1,i),i);
                        CF_vec_comp = app.loaded_curves(5,1:app.loaded_curves(1,1,i),i);
                        It_comp(i) = app.loaded_curves(6,1,i);

                        hold(ax1,'on')
                        plot(ax1,t_vec_comp,T_vec_comp);

                        hold(ax2,'on')
                        plot(ax2,t_vec_comp,CF_vec_comp);
    
                        hold(ax3,'on')
                        plot(ax3,t_vec_comp,P_vec_comp);
                        
                    end
                    close all
                    
                    % Must use switch for legend
                    switch app.loaded_curves_index
                        case 3 % Two loaded curves
                            l1 = ['PropSim: It = ',num2str(It),' Ns'];
                            l2 = cat(2,app.loaded_curves_list{1},': It = ',num2str(It_comp(1)),' Ns');
                            l3 = cat(2,app.loaded_curves_list{2},': It = ',num2str(It_comp(2)),' Ns');
                            legend(ax3,l1,l2,l3,'Position',[0.6 0.2 0.35 0.2])
                        case 4 % Three loaded curves
                            l1 = ['PropSim: It = ',num2str(It),' Ns'];
                            l2 = cat(2,app.loaded_curves_list{1},': It = ',num2str(It_comp(1)),' Ns');
                            l3 = cat(2,app.loaded_curves_list{2},': It = ',num2str(It_comp(2)),' Ns');
                            l4 = cat(2,app.loaded_curves_list{3},': It = ',num2str(It_comp(3)),' Ns');
                            legend(ax3,l1,l2,l3,l4,'Position',[0.6 0.2 0.35 0.2])                            
                        case 5 % Four loaded curves
                            l1 = ['PropSim: It = ',num2str(It),' Ns'];
                            l2 = cat(2,app.loaded_curves_list{1},': It = ',num2str(It_comp(1)),' Ns');
                            l3 = cat(2,app.loaded_curves_list{2},': It = ',num2str(It_comp(2)),' Ns');
                            l4 = cat(2,app.loaded_curves_list{3},': It = ',num2str(It_comp(3)),' Ns');
                            l5 = cat(2,app.loaded_curves_list{4},': It = ',num2str(It_comp(4)),' Ns');
                            legend(ax3,l1,l2,l3,l4,l5,'Position',[0.6 0.2 0.35 0.2])
                        otherwise
                            % Too much curves, will appear cluttered
                            % IMPROVEMENT: make this work
                    end
                    
                    app.PlotPanel.Title = 'Multiple plot comparison';
                                  
                end
            else
                % Probably better to leave it without functionality
            end
            
            
            UI_Resize(app,1);
        end

        % Button pushed function: SAVEButton
        function UI_savecase(app, event)
            % Loads all simulation properties into a data field which is saved as a csv 
            case_save = zeros(22,max([length(app.prop_prop),length(app.yield_ch(1,:)),length(app.uts_ch(1,:)),length(app.yield_no(1,:)),length(app.uts_no(1,:))]));
            % 22 might be subjected to change if the length of properties is included inside of all cases
            
            % Ambient
            case_save(1,1:2) = [app.Tamb.Value, app.Pamb.Value]; % Ambient properties
            
            % Chamber
            case_save(2,1:4) = [app.Dext_ch.Value, app.Dint_ch.Value, app.L_ch.Value, app.Blk_union.Value]; % Chamber geometry
            case_save(3,1:5) = app.prop_ch; % Chamber material properties
            case_save(4,1) = length(app.yield_ch(1,:)); % Number of yield data points
            case_save(5:6,1:case_save(4,1)) = app.yield_ch(1:2,:); % Chamber yield data points
            case_save(7,1) = length(app.uts_ch(1,:)); % Number of UTS data points
            case_save(8:9,1:case_save(7,1)) = app.uts_ch(1:2,:); % Chamber UTS data points
            
            % Propellant
            case_save(10,1:6) = [app.Dext_p.Value, app.Dint_p.Value, app.L0_p.Value, app.N_p.Value, app.Inh_out.Value, app.Inh_end.Value]; % Propellant geometry
            case_save(11,1:17) = app.prop_prop; % Propellant properties
            
            % Nozzle
            case_save(12,1:6) = [app.Di_no.Value, app.Dt_no.Value, app.De_no.Value, app.conv_no.Value, app.div_no.Value, app.Noz_union.Value]; % Nozzle geometry
            case_save(13,1:5) = app.prop_no;
            case_save(14,1) = length(app.yield_no(1,:)); % Number of yield data points
            case_save(15:16,1:case_save(14,1)) = app.yield_no(1:2,:); % Nozzle yield data points
            case_save(17,1) = length(app.uts_no(1,:)); % Number of UTS data points
            case_save(18:19,1:case_save(17,1)) = app.uts_no(1:2,:); % Nozzle UTS data points
            
            % Liner & Insulator
            case_save(20,1:3) = [app.t_li.Value, app.c_li.Value, app.lambda_li.Value]; % Liner properties
            case_save(21,1:3) = [app.t_ins.Value, app.c_ins.Value, app.lambda_ins.Value]; % Insulator properties
            
            % Simulation
            case_save(22,1:4) = [app.dt_sim.Value, app.N_sim.Value, app.n_sim.Value, app.t_sim.Value]; % Simulation parameters
            
            if ~isempty(app.Name_to_save.Value)
                fullname = cat(2,'C:\Program Files\PropSIM\application\Libraries\Case\',app.Name_to_save.Value,'.csv');
                writematrix(case_save,fullname);
            else
                
            end
            
        end

        % Button pushed function: SIMULATEButton
        function UI_SIMULATE(app, event)
            % We must check incompatiblities and edge cases:
            % 1 - Dext > Dint for prop and chamber - FAIL
            % 2 - Dextp + Liner + Ins < Dint ch - FAIL
            % 3 - Outside inhibited - FAIL
            % 4 - Convergence > 45º - WARNING
            % 5 - De | Do < Dt - FAIL
            % 6 - 


            app.SIMLOG.Value = 'PropSim V2.0.0 | CONSOLE WINDOW';
            app.SIMLOG.Value = cat(1,app.SIMLOG.Value,'-----------------------------------------------------');
            test = true;
            
            if app.Dext_p.Value < app.Dint_p.Value || app.Dext_ch.Value < app.Dint_ch.Value
                test = false;
                app.SIMLOG.Value = cat(1,app.SIMLOG.Value,'FATAL ERROR: Invalid chamber or grain geometry.');
            end
            if app.Dint_ch.Value < app.Dext_p.Value + 2*app.t_li.Value + 2*app.t_ins.Value
                test = false;
                app.SIMLOG.Value = cat(1,app.SIMLOG.Value,'FATAL ERROR: Invalid grain and liner/insulator geometry.');
            end
            if app.Inh_out.Value == 0
                test = false;
                app.SIMLOG.Value = cat(1,app.SIMLOG.Value,'FATAL ERROR: Outside must be inhibited.');
            end            
            if app.conv_no.Value > 45
                app.SIMLOG.Value = cat(1,app.SIMLOG.Value,'WARNING: Nozzle convergence angle too high. Less than 45º is recommended.');
            end
            if app.De_no.Value < app.Dt_no.Value || app.Di_no.Value < app.Dt_no.Value
                test = false;
                app.SIMLOG.Value = cat(1,app.SIMLOG.Value,'FATAL ERROR: Invalid nozzle geometry.');
            end
            if isempty(app.IGN)
                test = false;
                app.SIMLOG.Value = cat(1,app.SIMLOG.Value,'FATAL ERROR: No igniter selected.');
            end
            
            if test
                app.SIMLOG.Value = cat(1,app.SIMLOG.Value,'Executing...'); 
                tiledlayout(app.PlotPanel,1,1);
                app.PlotPanel.Title = 'Plot';
                UI_Resize(app,1)
                MAIN(app);
            end
            
        end

        % Button pushed function: EDITMat_ch
        function UI_editmat_ch(app, event)
            app.DialogApp = MatWindow(app, app.prop_ch, app.yield_ch, app.uts_ch, 1);
        end

        % Button pushed function: EDITProp
        function UI_editprop(app, event)
            app.DialogApp = PropWindow(app, app.prop_prop);
        end

        % Button pushed function: EDITMat_no
        function UI_editmat_no(app, event)
            app.DialogApp = MatWindow(app, app.prop_no, app.yield_no, app.uts_no, 0);
        end

        % Button pushed function: EDITIgn
        function UI_editign(app, event)
            app.DialogApp = IgnWindow(app, app.IGN);
        end

        % Button pushed function: CLEARButton
        function CLEARButtonPushed(app, event)
            % Only empty tiledlayout
            tiledlayout(app.PlotPanel,1,1);
            app.PlotPanel.Title = 'Plot';
            
            % Restart all loaded_curves parameters
            app.loaded_curves = zeros(6,500,10);
            app.loaded_curves_index = 1;
            app.loaded_curves_list = strings(10,1); 
            
            app.List_curves.Value = 'No curve is currenly loaded';
            
        end

        % Button pushed function: CANCELButton
        function CANCELButtonPushed(app, event)
            app.f_cancel = true;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AMBIENTLabel = uilabel(app.UIFigure);
            app.AMBIENTLabel.Text = 'AMBIENT';
            app.CHAMBERLabel = uilabel(app.UIFigure);
            app.CHAMBERLabel.Text = 'CHAMBER';
            app.MATERIALLabel = uilabel(app.UIFigure);
            app.MATERIALLabel.Text = 'MATERIAL';
            app.GRAINLabel = uilabel(app.UIFigure);
            app.GRAINLabel.Text = 'GRAIN';
            app.PROPELLANTLabel = uilabel(app.UIFigure);
            app.PROPELLANTLabel.Text = 'PROPELLANT';
            app.NOZZLELabel = uilabel(app.UIFigure);
            app.NOZZLELabel.Text = 'NOZZLE';
            app.MATERIALLabel_2 = uilabel(app.UIFigure);
            app.MATERIALLabel_2.Text = 'MATERIAL';
            app.LINERLabel = uilabel(app.UIFigure);
            app.LINERLabel.Text = 'LINER';
            app.INSULATORLabel = uilabel(app.UIFigure);
            app.INSULATORLabel.Text = 'INSULATOR';
            app.SIMULATORLabel = uilabel(app.UIFigure);
            app.SIMULATORLabel.Text = 'SIMULATOR';
            app.CRACKINGLabel = uilabel(app.UIFigure);
            app.CRACKINGLabel.Text = 'CRACKING';
            app.IGNITERLabel = uilabel(app.UIFigure);
            app.IGNITERLabel.Text = 'IGNITER';
            app.Inh_out = uicheckbox(app.UIFigure);
            app.Inh_out.Text = '';
            app.Inh_end = uicheckbox(app.UIFigure);
            app.Inh_end.Text = '';
            app.OutsideinhibitedLabel = uilabel(app.UIFigure);
            app.OutsideinhibitedLabel.Text = {'Outside'; 'inhibited?'};
            app.EndsinhibitedLabel = uilabel(app.UIFigure);
            app.EndsinhibitedLabel.Text = {'Ends'; 'inhibited?'};
            app.Mat_ch_label = uilabel(app.UIFigure);
            app.Mat_ch_label.Text = 'No material preset defined for the chamber';
            app.Mat_no_label = uilabel(app.UIFigure);
            app.Mat_no_label.Text = 'No material preset defined for the nozzle';
            app.Prop_label = uilabel(app.UIFigure);
            app.Prop_label.Text = 'No propellant preset defined for the grain';
            app.Ign_label = uilabel(app.UIFigure);
            app.Ign_label.Text = 'No igniter preset defined for the simulation';
            app.Case_preset = uilabel(app.UIFigure);
            app.Case_preset.Text = 'Loaded case preset';
            app.Loaded_name_case = uilabel(app.UIFigure);
            app.Loaded_name_case.Text = 'No case preset is loaded';
            app.SIMULATEButton = uibutton(app.UIFigure, 'push');
            app.SIMULATEButton.ButtonPushedFcn = createCallbackFcn(app, @UI_SIMULATE, true);
            app.SIMULATEButton.Icon = 'execution_icon.png';
            app.SIMULATEButton.Text = 'SIMULATE';
            app.PLOTButton = uibutton(app.UIFigure, 'push');
            app.PLOTButton.ButtonPushedFcn = createCallbackFcn(app, @UI_PLOT, true);
            app.PLOTButton.Icon = 'plot_icon_2.png';
            app.PLOTButton.Text = 'PLOT';
            app.CLEARButton = uibutton(app.UIFigure, 'push');
            app.CLEARButton.ButtonPushedFcn = createCallbackFcn(app, @CLEARButtonPushed, true);
            app.CLEARButton.Icon = 'undo_icon.png';
            app.CLEARButton.Text = 'CLEAR';
            app.TKEditFieldLabel = uilabel(app.UIFigure);
            app.TKEditFieldLabel.Text = 'T (K)';
            app.Tamb = uieditfield(app.UIFigure, 'numeric');
            app.Tamb.Limits = [0 Inf];
            app.PbarEditFieldLabel = uilabel(app.UIFigure);
            app.PbarEditFieldLabel.Text = 'P (bar)';
            app.Pamb = uieditfield(app.UIFigure, 'numeric');
            app.Pamb.Limits = [0 Inf];
            app.DextmmEditFieldLabel = uilabel(app.UIFigure);
            app.DextmmEditFieldLabel.Text = 'Dext (mm)';
            app.Dext_ch = uieditfield(app.UIFigure, 'numeric');
            app.Dext_ch.Limits = [0 Inf];
            app.DintmmEditFieldLabel = uilabel(app.UIFigure);
            app.DintmmEditFieldLabel.Text = 'Dint (mm)';
            app.Dint_ch = uieditfield(app.UIFigure, 'numeric');
            app.Dint_ch.Limits = [0 Inf];
            app.LchmmEditFieldLabel = uilabel(app.UIFigure);
            app.LchmmEditFieldLabel.Text = 'Lch (mm)';
            app.L_ch = uieditfield(app.UIFigure, 'numeric');
            app.L_ch.Limits = [0 Inf];
            app.BlkunionNEditFieldLabel = uilabel(app.UIFigure);
            app.BlkunionNEditFieldLabel.Text = 'Blk union (N)';
            app.Blk_union = uieditfield(app.UIFigure, 'numeric');
            app.Blk_union.Limits = [0 Inf];
            app.DextmmEditField_2Label = uilabel(app.UIFigure);
            app.DextmmEditField_2Label.Text = 'Dext (mm)';
            app.Dext_p = uieditfield(app.UIFigure, 'numeric');
            app.Dext_p.Limits = [0 Inf];
            app.DintmmEditField_2Label = uilabel(app.UIFigure);
            app.DintmmEditField_2Label.Text = 'Dint (mm)';
            app.Dint_p = uieditfield(app.UIFigure, 'numeric');
            app.Dint_p.Limits = [0 Inf];
            app.L0mmEditFieldLabel = uilabel(app.UIFigure);
            app.L0mmEditFieldLabel.Text = 'L0 (mm)';
            app.L0_p = uieditfield(app.UIFigure, 'numeric');
            app.L0_p.Limits = [0 Inf];
            app.NgrainsLabel = uilabel(app.UIFigure);
            app.NgrainsLabel.Text = 'Nº grains';
            app.N_p = uieditfield(app.UIFigure, 'numeric');
            app.N_p.Limits = [0 4];
            app.DimmEditFieldLabel = uilabel(app.UIFigure);
            app.DimmEditFieldLabel.Text = 'Di (mm)';
            app.Di_no = uieditfield(app.UIFigure, 'numeric');
            app.Di_no.Limits = [0 Inf];
            app.DtmmEditFieldLabel = uilabel(app.UIFigure);
            app.DtmmEditFieldLabel.Text = 'Dt (mm)';
            app.Dt_no = uieditfield(app.UIFigure, 'numeric');
            app.Dt_no.Limits = [0 Inf];
            app.DemmEditFieldLabel = uilabel(app.UIFigure);
            app.DemmEditFieldLabel.Text = 'De (mm)';
            app.De_no = uieditfield(app.UIFigure, 'numeric');
            app.De_no.Limits = [0 Inf];
            app.convEditFieldLabel = uilabel(app.UIFigure);
            app.convEditFieldLabel.Text = 'Φconv (º)';
            app.conv_no = uieditfield(app.UIFigure, 'numeric');
            app.conv_no.Limits = [0 90];
            app.divEditFieldLabel = uilabel(app.UIFigure);
            app.divEditFieldLabel.Text = 'Φdiv (º)';
            app.div_no = uieditfield(app.UIFigure, 'numeric');
            app.div_no.Limits = [0 90];
            app.tmmEditFieldLabel = uilabel(app.UIFigure);
            app.tmmEditFieldLabel.Text = 't (mm)';
            app.t_li = uieditfield(app.UIFigure, 'numeric');
            app.t_li.Limits = [0 Inf];
            app.cJkgKEditFieldLabel = uilabel(app.UIFigure);
            app.cJkgKEditFieldLabel.Text = 'c (J/kgK)';
            app.c_li = uieditfield(app.UIFigure, 'numeric');
            app.c_li.Limits = [0 Inf];
            app.WmKEditFieldLabel = uilabel(app.UIFigure);
            app.WmKEditFieldLabel.Text = 'λ (W/mK)';
            app.lambda_li = uieditfield(app.UIFigure, 'numeric');
            app.lambda_li.Limits = [0 Inf];
            app.tmmEditField_2Label = uilabel(app.UIFigure);
            app.tmmEditField_2Label.Text = 't (mm)';
            app.t_ins = uieditfield(app.UIFigure, 'numeric');
            app.t_ins.Limits = [0 Inf];
            app.cJkgKEditField_2Label = uilabel(app.UIFigure);
            app.cJkgKEditField_2Label.Text = 'c (J/kgK)';
            app.c_ins = uieditfield(app.UIFigure, 'numeric');
            app.c_ins.Limits = [0 Inf];
            app.lambda_ins = uieditfield(app.UIFigure, 'numeric');
            app.lambda_ins.Limits = [0 Inf];
            app.WmKEditField_2Label = uilabel(app.UIFigure);
            app.WmKEditField_2Label.Text = 'λ (W/mK)';
            app.tsEditFieldLabel = uilabel(app.UIFigure);
            app.tsEditFieldLabel.Text = 'Δt (s)';
            app.dt_sim = uieditfield(app.UIFigure, 'numeric');
            app.dt_sim.Limits = [0 0.001];
            app.FVMEditFieldLabel = uilabel(app.UIFigure);
            app.FVMEditFieldLabel.Text = 'FVM';
            app.N_sim = uieditfield(app.UIFigure, 'numeric');
            app.N_sim.Limits = [0 Inf];
            app.nEditFieldLabel = uilabel(app.UIFigure);
            app.nEditFieldLabel.Text = 'n';
            app.n_sim = uieditfield(app.UIFigure, 'numeric');
            app.n_sim.Limits = [0 Inf];
            app.SimtsEditFieldLabel = uilabel(app.UIFigure);
            app.SimtsEditFieldLabel.Text = 'Sim t (s)';
            app.t_sim = uieditfield(app.UIFigure, 'numeric');
            app.t_sim.Limits = [0 120];
            app.PropellantcrackingtypeDropDownLabel = uilabel(app.UIFigure);
            app.PropellantcrackingtypeDropDownLabel.Text = 'Propellant cracking type';
            app.Crack_type = uidropdown(app.UIFigure);
            app.Crack_type.Items = {'No cracking', 'Microcracks (might be fatal)', 'Bridge gaps (fatal)', 'Open gaps (fatal)'};
            app.Crack_type.Value = 'No cracking';
            app.ProvideanametothecaseEditFieldLabel = uilabel(app.UIFigure);
            app.ProvideanametothecaseEditFieldLabel.Text = 'Provide a name to the case';
            app.Name_to_save = uieditfield(app.UIFigure, 'text');
            app.NozunionNEditFieldLabel = uilabel(app.UIFigure);
            app.NozunionNEditFieldLabel.Text = 'Noz union (N)';
            app.Noz_union = uieditfield(app.UIFigure, 'numeric');
            app.Noz_union.Limits = [0 Inf];
            app.LOADCURVESButton = uibutton(app.UIFigure, 'push');
            app.LOADCURVESButton.ButtonPushedFcn = createCallbackFcn(app, @UI_loadcurves, true);
            app.LOADCURVESButton.Text = {'LOAD '; 'CURVES'};
            app.List_curves = uitextarea(app.UIFigure);
            app.List_curves.Value = {'No curve is currenly loaded'};
            app.SAVEButton = uibutton(app.UIFigure, 'push');
            app.SAVEButton.ButtonPushedFcn = createCallbackFcn(app, @UI_savecase, true);
            app.SAVEButton.Icon = 'save_icon.png';
            app.SAVEButton.Text = 'SAVE';
            app.OPENMat_ch = uibutton(app.UIFigure, 'push');
            app.OPENMat_ch.ButtonPushedFcn = createCallbackFcn(app, @UI_openmat_ch, true);
            app.OPENMat_ch.Icon = 'folder_icon.png';
            app.OPENMat_ch.Text = 'OPEN';
            app.OPENCase = uibutton(app.UIFigure, 'push');
            app.OPENCase.ButtonPushedFcn = createCallbackFcn(app, @UI_opencase, true);
            app.OPENCase.Icon = 'folder_icon.png';
            app.OPENCase.Text = 'OPEN';
            app.OPENProp = uibutton(app.UIFigure, 'push');
            app.OPENProp.ButtonPushedFcn = createCallbackFcn(app, @UI_openprop, true);
            app.OPENProp.Icon = 'folder_icon.png';
            app.OPENProp.Text = 'OPEN';
            app.OPENMat_no = uibutton(app.UIFigure, 'push');
            app.OPENMat_no.ButtonPushedFcn = createCallbackFcn(app, @UI_openmat_no, true);
            app.OPENMat_no.Icon = 'folder_icon.png';
            app.OPENMat_no.Text = 'OPEN';
            app.OPENIgn = uibutton(app.UIFigure, 'push');
            app.OPENIgn.ButtonPushedFcn = createCallbackFcn(app, @UI_openign, true);
            app.OPENIgn.Icon = 'folder_icon.png';
            app.OPENIgn.Text = 'OPEN';
            app.EDITMat_ch = uibutton(app.UIFigure, 'push');
            app.EDITMat_ch.ButtonPushedFcn = createCallbackFcn(app, @UI_editmat_ch, true);
            app.EDITMat_ch.Icon = 'edit_icon.png';
            app.EDITMat_ch.Text = 'EDIT';
            app.EDITProp = uibutton(app.UIFigure, 'push');
            app.EDITProp.ButtonPushedFcn = createCallbackFcn(app, @UI_editprop, true);
            app.EDITProp.Icon = 'edit_icon.png';
            app.EDITProp.Text = 'EDIT';
            app.EDITMat_no = uibutton(app.UIFigure, 'push');
            app.EDITMat_no.ButtonPushedFcn = createCallbackFcn(app, @UI_editmat_no, true);
            app.EDITMat_no.Icon = 'edit_icon.png';
            app.EDITMat_no.Text = 'EDIT';
            app.SIMULATIONLOGTextAreaLabel = uilabel(app.UIFigure);
            app.SIMULATIONLOGTextAreaLabel.Text = 'SIMULATION LOG';
            app.SIMLOG = uitextarea(app.UIFigure);
            app.SIMLOG.Editable = 'off';
            app.EDITIgn = uibutton(app.UIFigure, 'push');
            app.EDITIgn.ButtonPushedFcn = createCallbackFcn(app, @UI_editign, true);
            app.EDITIgn.Icon = 'edit_icon.png';
            app.EDITIgn.Text = 'EDIT';
            app.PlotPanel = uipanel(app.UIFigure);
            app.PlotPanel.Title = 'Plot';
            app.CANCELButton = uibutton(app.UIFigure, 'push');
            app.CANCELButton.ButtonPushedFcn = createCallbackFcn(app, @CANCELButtonPushed, true);
            app.CANCELButton.Icon = 'cancel_icon.png';
            app.CANCELButton.Text = 'CANCEL';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MySim

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end