classdef PropWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Name_to_save                   matlab.ui.control.EditField
        ProvideanametothepropellantLabel  matlab.ui.control.Label
        SAVEButton                     matlab.ui.control.Button
        GasexhaustpropertiesPanel      matlab.ui.container.Panel
        UILambda_g                     matlab.ui.control.NumericEditField
        ThermalconductivityWmKLabel    matlab.ui.control.Label
        UIXw                           matlab.ui.control.NumericEditField
        MolarfractionofwaterinexhaustLabel  matlab.ui.control.Label
        UIMu                           matlab.ui.control.NumericEditField
        DynamicviscosityPasLabel       matlab.ui.control.Label
        UIHeat_cap_vct                 matlab.ui.control.NumericEditField
        SpecificheatatconstantvolumeJmolKLabel  matlab.ui.control.Label
        UIGamma                        matlab.ui.control.NumericEditField
        RatioofspecificheatsLabel      matlab.ui.control.Label
        UIMw                           matlab.ui.control.NumericEditField
        MeanmolarmassgmolLabel         matlab.ui.control.Label
        PropellantpropertiesPanel      matlab.ui.container.Panel
        UIHeat_cap                     matlab.ui.control.NumericEditField
        HeatcapacityJkgKLabel          matlab.ui.control.Label
        UILambda_p                     matlab.ui.control.NumericEditField
        ThermalconductivityWmKEditFieldLabel  matlab.ui.control.Label
        UIDens_ratio                   matlab.ui.control.NumericEditField
        Densityratio01Label            matlab.ui.control.Label
        UIIdeal_dens                   matlab.ui.control.NumericEditField
        Idealdensitykgm3Label          matlab.ui.control.Label
        FlameparametersPanel           matlab.ui.container.Panel
        UITautoig                      matlab.ui.control.NumericEditField
        AutoignitiontemperatureKLabel  matlab.ui.control.Label
        UIComb_eff                     matlab.ui.control.NumericEditField
        CombustionefficiencyLabel      matlab.ui.control.Label
        UITflame                       matlab.ui.control.NumericEditField
        AdiabaticflametemperatureKLabel  matlab.ui.control.Label
        BurningparametersPanel         matlab.ui.container.Panel
        UIBeta                         matlab.ui.control.NumericEditField
        ErosiveexponentmsPaLabel       matlab.ui.control.Label
        UIAlpha                        matlab.ui.control.NumericEditField
        ErosiveconstantmsPaLabel       matlab.ui.control.Label
        UIComb_index                   matlab.ui.control.NumericEditField
        CombustionindexnLabel          matlab.ui.control.Label
        UITemp_coef                    matlab.ui.control.NumericEditField
        TemperaturecoefficientammsMPa2Label  matlab.ui.control.Label
    end

    
    properties (Access = public)
        CallingApp % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, P)
            % Saves main app and inputs
            app.CallingApp = mainapp;
            
            app.UITemp_coef.Value = P(1);
            app.UIComb_index.Value = P(2);
            app.UIIdeal_dens.Value = P(3);
            app.UIDens_ratio.Value = P(4);
            app.UILambda_p.Value = P(5);
            app.UIHeat_cap.Value = P(6);
            app.UIMw.Value = P(7);
            app.UIHeat_cap_vct.Value = P(8);
            app.UIGamma.Value = P(9);
            app.UIMu.Value = P(10);
            app.UILambda_g.Value = P(11);
            app.UITflame.Value = P(12);
            app.UITautoig.Value = P(13);
            app.UIAlpha.Value = P(14);
            app.UIBeta.Value = P(15);
            app.UIComb_eff.Value = P(16);
            app.UIXw.Value = P(17);
        end

        % Button pushed function: SAVEButton
        function SAVEButtonPushed(app, event)
            if ~isempty(app.Name_to_save.Value)
                full_prop_name = cat(2,'C:\Program Files\PropSIM\application\Libraries\Prop\',app.Name_to_save.Value,'.csv');    
                prop_save = zeros(2,17);
                prop_save(1,1) = 17;
                prop_save(2,1:length(prop_save(1,:))) = [app.UITemp_coef.Value, app.UIComb_index.Value, app.UIIdeal_dens.Value, app.UIDens_ratio.Value... 
                app.UILambda_p.Value, app.UIHeat_cap.Value, app.UIMw.Value, app.UIHeat_cap_vct.Value, app.UIGamma.Value, app.UIMu.Value, app.UILambda_g.Value...
                app.UITflame.Value, app.UITautoig.Value, app.UIAlpha.Value, app.UIBeta.Value, app.UIComb_eff.Value,  app.UIXw.Value];
                
                writematrix(prop_save,full_prop_name);
                UI_editprop_update(app.CallingApp,prop_save,cat(2,app.Name_to_save.Value,'.csv'));
                
                delete(app);
            else
                
            end 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.BurningparametersPanel = uipanel(app.UIFigure);
            app.BurningparametersPanel.Title = 'Burning parameters';
            app.TemperaturecoefficientammsMPa2Label = uilabel(app.BurningparametersPanel);
            app.TemperaturecoefficientammsMPa2Label.Text = 'Temperature coefficient a (mm/s·MPa^2)';
            app.UITemp_coef = uieditfield(app.BurningparametersPanel, 'numeric');
            app.CombustionindexnLabel = uilabel(app.BurningparametersPanel);
            app.CombustionindexnLabel.Text = 'Combustion index n';
            app.UIComb_index = uieditfield(app.BurningparametersPanel, 'numeric');
            app.ErosiveconstantmsPaLabel = uilabel(app.BurningparametersPanel);
            app.ErosiveconstantmsPaLabel.Text = 'Erosive constant (m/s, Pa)';
            app.UIAlpha = uieditfield(app.BurningparametersPanel, 'numeric');
            app.ErosiveexponentmsPaLabel = uilabel(app.BurningparametersPanel);
            app.ErosiveexponentmsPaLabel.Text = 'Erosive exponent (m/s, Pa)';
            app.UIBeta = uieditfield(app.BurningparametersPanel, 'numeric');
            app.UIBeta.Editable = 'off';
            app.UIBeta.Tooltip = {'It''s given as fixed in SI units by Sutton'};
            app.UIBeta.Value = 53;
            app.FlameparametersPanel = uipanel(app.UIFigure);
            app.FlameparametersPanel.Title = 'Flame parameters';
            app.AdiabaticflametemperatureKLabel = uilabel(app.FlameparametersPanel);
            app.AdiabaticflametemperatureKLabel.Text = 'Adiabatic flame temperature (K)';
            app.UITflame = uieditfield(app.FlameparametersPanel, 'numeric');
            app.CombustionefficiencyLabel = uilabel(app.FlameparametersPanel);
            app.CombustionefficiencyLabel.Text = 'Combustion efficiency';
            app.UIComb_eff = uieditfield(app.FlameparametersPanel, 'numeric');
            app.AutoignitiontemperatureKLabel = uilabel(app.FlameparametersPanel);
            app.AutoignitiontemperatureKLabel.Text = 'Autoignition temperature (K)';
            app.UITautoig = uieditfield(app.FlameparametersPanel, 'numeric');
            app.PropellantpropertiesPanel = uipanel(app.UIFigure);
            app.PropellantpropertiesPanel.Title = 'Propellant properties';
            app.Idealdensitykgm3Label = uilabel(app.PropellantpropertiesPanel);
            app.Idealdensitykgm3Label.Text = 'Ideal density (kg/m3)';
            app.UIIdeal_dens = uieditfield(app.PropellantpropertiesPanel, 'numeric');
            app.Densityratio01Label = uilabel(app.PropellantpropertiesPanel);
            app.Densityratio01Label.Text = 'Density ratio (0-1)';
            app.UIDens_ratio = uieditfield(app.PropellantpropertiesPanel, 'numeric');
            app.ThermalconductivityWmKEditFieldLabel = uilabel(app.PropellantpropertiesPanel);
            app.ThermalconductivityWmKEditFieldLabel.Text = {'Thermal conductivity'; '(W/mK)'};
            app.UILambda_p = uieditfield(app.PropellantpropertiesPanel, 'numeric');
            app.HeatcapacityJkgKLabel = uilabel(app.PropellantpropertiesPanel);
            app.HeatcapacityJkgKLabel.Text = 'Heat capacity (J/kgK)';
            app.UIHeat_cap = uieditfield(app.PropellantpropertiesPanel, 'numeric');
            app.GasexhaustpropertiesPanel = uipanel(app.UIFigure);
            app.GasexhaustpropertiesPanel.Title = 'Gas exhaust properties';
            app.MeanmolarmassgmolLabel = uilabel(app.GasexhaustpropertiesPanel);
            app.MeanmolarmassgmolLabel.Text = 'Mean molar mass (g/mol)';
            app.UIMw = uieditfield(app.GasexhaustpropertiesPanel, 'numeric');
            app.RatioofspecificheatsLabel = uilabel(app.GasexhaustpropertiesPanel);
            app.RatioofspecificheatsLabel.Text = 'Ratio of specific heats';
            app.UIGamma = uieditfield(app.GasexhaustpropertiesPanel, 'numeric');
            app.SpecificheatatconstantvolumeJmolKLabel = uilabel(app.GasexhaustpropertiesPanel);
            app.SpecificheatatconstantvolumeJmolKLabel.Text = {'Specific heat at constant'; 'volume (J/molK)'};
            app.UIHeat_cap_vct = uieditfield(app.GasexhaustpropertiesPanel, 'numeric');
            app.DynamicviscosityPasLabel = uilabel(app.GasexhaustpropertiesPanel);
            app.DynamicviscosityPasLabel.Text = 'Dynamic viscosity (Pa·s)';
            app.UIMu = uieditfield(app.GasexhaustpropertiesPanel, 'numeric');
            app.MolarfractionofwaterinexhaustLabel = uilabel(app.GasexhaustpropertiesPanel);
            app.MolarfractionofwaterinexhaustLabel.Text = {'Molar fraction of water'; 'in exhaust'};
            app.UIXw = uieditfield(app.GasexhaustpropertiesPanel, 'numeric');
            app.ThermalconductivityWmKLabel = uilabel(app.GasexhaustpropertiesPanel);
            app.ThermalconductivityWmKLabel.Text = {'Thermal conductivity'; '(W/mK)'};
            app.UILambda_g = uieditfield(app.GasexhaustpropertiesPanel, 'numeric');
            app.SAVEButton = uibutton(app.UIFigure, 'push');
            app.SAVEButton.ButtonPushedFcn = createCallbackFcn(app, @SAVEButtonPushed, true);
            app.SAVEButton.Icon = 'save_icon.png';
            app.SAVEButton.Text = 'SAVE';
            app.ProvideanametothepropellantLabel = uilabel(app.UIFigure);
            app.ProvideanametothepropellantLabel.Text = 'Provide a name to the propellant';
            app.Name_to_save = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PropWindow(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end